classdef CageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RESETButton                    matlab.ui.control.Button
        MoSys_logo                     matlab.ui.control.Image
        VUT_logo                       matlab.ui.control.Image
        PredictionmethodsLabel         matlab.ui.control.Label
        CaGeAppComputeraidedapproachforGenomicspredictionLabel  matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        CorrelationcoefEditField_MultiVar  matlab.ui.control.NumericEditField
        CorrelationcoefEditField_2Label  matlab.ui.control.Label
        MAEEditField_MultiVar          matlab.ui.control.NumericEditField
        MAEEditField_2Label            matlab.ui.control.Label
        MSEEditField_MultiVar          matlab.ui.control.NumericEditField
        MSEEditField_3Label_3          matlab.ui.control.Label
        CorrelationcoefEditField       matlab.ui.control.NumericEditField
        CorrelationcoefEditField_3Label  matlab.ui.control.Label
        MAEEditField                   matlab.ui.control.NumericEditField
        MSEEditField_3Label_2          matlab.ui.control.Label
        MSEEditField                   matlab.ui.control.NumericEditField
        MSEEditField_3Label            matlab.ui.control.Label
        UniVarSample                   matlab.ui.control.Spinner
        SamplenoSpinnerLabel_2         matlab.ui.control.Label
        MultivariatePredictionButton   matlab.ui.control.Button
        UnivariatePredictionButton     matlab.ui.control.Button
        PredictionsGenome2MetabolomeLabel  matlab.ui.control.Label
        MultiBoxPlot                   matlab.ui.control.UIAxes
        UniBoxPlot                     matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        GWASButton_app                 matlab.ui.control.Button
        SeeindetailButton_4            matlab.ui.control.Button
        DotplotButton                  matlab.ui.control.Button
        HistogramButton                matlab.ui.control.Button
        SeeindetailButton              matlab.ui.control.Button
        DotPlotSampleSpinner           matlab.ui.control.Spinner
        SamplenoSpinnerLabel           matlab.ui.control.Label
        HistPlotSampleSpinner          matlab.ui.control.Spinner
        SamplenoSpinner_2Label         matlab.ui.control.Label
        mapcaplotButton                matlab.ui.control.Button
        MetaboliteInfoLabel            matlab.ui.control.Label
        GenomicInfoLabel               matlab.ui.control.Label
        VisualizationofinputdataLabel  matlab.ui.control.Label
        Histplot                       matlab.ui.control.UIAxes
        Dotplot                        matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        PRSButton_app                  matlab.ui.control.Button
        CNNCheckBox                    matlab.ui.control.CheckBox
        LSTMCheckBox                   matlab.ui.control.CheckBox
        RandomForestCheckBox           matlab.ui.control.CheckBox
        PLSCheckBox                    matlab.ui.control.CheckBox
        GPRCheckBox                    matlab.ui.control.CheckBox
        DLapproachLabel                matlab.ui.control.Label
        NonlinearapproachLabel         matlab.ui.control.Label
        LinearapproachLabel            matlab.ui.control.Label
        SVRCheckBox                    matlab.ui.control.CheckBox
        LASSOCheckBox                  matlab.ui.control.CheckBox
        Panel                          matlab.ui.container.Panel
        UITablePhe                     matlab.ui.control.Table
        LoadPhenotypeInfo              matlab.ui.control.Button
        InputLabel                     matlab.ui.control.Label
        UITableMet                     matlab.ui.control.Table
        UITableGen                     matlab.ui.control.Table
        LoadMetabolomeInfoButton       matlab.ui.control.Button
        LoadGenomicInfoButton          matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path
        dataG;        % Genomic data
        dataG_no;     % Genomic data – only number
        dataM;        % Metabolomic data
        dataM_no;     % Metabolomic data – only number
        dataPhe;      % Phenotype data

        i_dotplotSample; i_histplotSample; % Selected sample for visualization

        %Check Box Vars 
        LASSOCheckBoxValue; BLUPCheckBoxValue; SVRCheckBoxValue;
        PLSCheckBoxValue; RandomForestCheckBoxValue; LSTMCheckBoxValue; 
        CNNCheckBoxValue; CheckBoxSum;

        %Univariate vars__________________________________________________
        %Selected metabolites for prediction
        i_UniVarSample; UniPred_G; UniPred_M;
        %_________________________________________________________________


        Pred_Val; %Predictied values
        Orig_Val; %Originals values

        %Outputs vars
        OutputMSE; OutputMAE;

    end
    
    methods (Access = private)
        
        function updateTable(app)
            % Get Table UI component data
            app.dataG.Data = app.UITableGen.DisplayData;            
            app.dataM.Data = app.UITableMet.DisplayData; 
        end

        function updateDotPlot(app)
            plot(app.Dotplot, app.dataG_no(:,app.i_dotplotSample),'o');
        end

        function updateHistPlot(app)
            histogram(app.Histplot,app.dataM_no(:,app.i_histplotSample));
        end


        function LASSO(app)
            % LASSO prediction approach:
            % Predict students' exam scores using lasso and the elastic net method.
            X = app.UniPred_G;
            y = app.UniPred_M;

            n = length(y);
            c = cvpartition(n,'HoldOut',0.3);
            idxTrain = training(c,1);
            idxTest = ~idxTrain;
            XTrain = X(idxTrain,:);
            yTrain = y(idxTrain);
            XTest = X(idxTest,:);
            yTest = y(idxTest);
            [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',0.75,'CV',5); %cross-val = 5
            idxLambda1SE = FitInfo.Index1SE;
            coef = B(:,idxLambda1SE);
            coef0 = FitInfo.Intercept(idxLambda1SE);

            app.Pred_Val = XTest*coef + coef0;
            app.Orig_Val = yTest;
        end

        function GPR(app)
            %Fit a Gaussian process regression (GPR) model

            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            for i=1:5
                if i == 1
                    testG = app.UniPred_G(1: floor(size(app.UniPred_G,1)/5)*i,:);
                    testM = app.UniPred_M(1: floor(size(app.UniPred_G,1)/5)*i,:);

                    trainG = app.UniPred_G(floor(size(app.UniPred_G,1)/5)*i +1: end,:);
                    trainM = app.UniPred_M(floor(size(app.UniPred_G,1)/5)*i +1: end,:);
                else
                    testG = app.UniPred_G(floor(size(app.UniPred_G,1)/5)*(i-1) : floor(size(app.UniPred_G,1)/5)*i,:);
                    testM = app.UniPred_M(floor(size(app.UniPred_M,1)/5)*(i-1) : floor(size(app.UniPred_M,1)/5)*i,:);

                    trainG = app.UniPred_G([1: floor(size(app.UniPred_G,1)/5)*(i-1)-1, floor(size(app.UniPred_G,1)/5)*i:end],:);
                    trainM = app.UniPred_M([1: floor(size(app.UniPred_M,1)/5)*(i-1)-1, floor(size(app.UniPred_M,1)/5)*i:end],:);
                end
                X = trainG;
                Y = trainM;

                if size(Y,2) == 1
                %Univariate
                    gprMdl1 = fitrgp(X,Y);
                    x = testG;
                    [ypred1,~,~] = predict(gprMdl1,x);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; testM];
                else
                %Multivariate
                    gprMdl1 = fitrgp(X,mean(Y,2));
                    x = testG;
                    [ypred1,~,~] = predict(gprMdl1,x);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; mean(testM,2)];
                end

            end
            
                app.Pred_Val = ypred11;
                app.Orig_Val = testM1;

            %80:20 TRAINING:TESTING
            %trainG = app.UniPred_G(1: floor(0.8*size(app.UniPred_G,1)),:);
            %testG = app.UniPred_G(floor(0.8*size(app.UniPred_G,1))+1 : end,:);
            %trainM = app.UniPred_M(1: floor(0.8*size(app.UniPred_M,1)),:);
            %testM = app.UniPred_M(floor(0.8*size(app.UniPred_M,1))+1 : end,:);
            %
            %X = trainG;
            %Y = trainM;
            %gprMdl1 = fitrgp(X,Y);
            %
            %x = testG;
            %[ypred1,~,~] = predict(gprMdl1,x);
            %
            %app.Pred_Val = ypred1;
            %app.Orig_Val = testM;

        end

        function SVR(app)
            % SVR
            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];
            for i=1:5
                if i == 1
                    testG = app.UniPred_G(1: floor(size(app.UniPred_G,1)/5)*i,:);
                    testM = app.UniPred_M(1: floor(size(app.UniPred_G,1)/5)*i,:);

                    trainG = app.UniPred_G(floor(size(app.UniPred_G,1)/5)*i +1: end,:);
                    trainM = app.UniPred_M(floor(size(app.UniPred_G,1)/5)*i +1: end,:);
                else
                    testG = app.UniPred_G(floor(size(app.UniPred_G,1)/5)*(i-1) : floor(size(app.UniPred_G,1)/5)*i,:);
                    testM = app.UniPred_M(floor(size(app.UniPred_M,1)/5)*(i-1) : floor(size(app.UniPred_M,1)/5)*i,:);

                    trainG = app.UniPred_G([1: floor(size(app.UniPred_G,1)/5)*(i-1)-1, floor(size(app.UniPred_G,1)/5)*i:end],:);
                    trainM = app.UniPred_M([1: floor(size(app.UniPred_M,1)/5)*(i-1)-1, floor(size(app.UniPred_M,1)/5)*i:end],:);
                end
                X = trainG;
                Y = trainM;

                if size(Y,2) == 1
                %Univariate
                    MdlLin = fitrsvm(X,Y);
                    ypred1 = predict(MdlLin,testG);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; testM];
                else
                %Multivariate
                    MdlLin = fitrsvm(X,mean(Y,2));
                    ypred1 = predict(MdlLin,testG);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; mean(testM,2)];
                end

            end
            
                app.Pred_Val = ypred11;
                app.Orig_Val = testM1;

            %80:20 TRAINING:TESTING
            %trainG = app.UniPred_G(1: floor(0.8*size(app.UniPred_G,1)),: );
            %testG = app.UniPred_G(floor(0.8*size(app.UniPred_G,1))+1 : end,:);
            %trainM = app.UniPred_M(1: floor(0.8*size(app.UniPred_M,1)),: );
            %testM = app.UniPred_M(floor(0.8*size(app.UniPred_M,1))+1 : end,:);
            %    
            %X = trainG;
            %Y = trainM;
            %
            %MdlLin = fitrsvm(X,Y);
            %
            %app.Pred_Val = predict(MdlLin,testG);
            %app.Orig_Val = testM;
        end

        function PLS(app)
            % PLS
            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];
            for i=1:5
                if i == 1
                    testG = app.UniPred_G(1: floor(size(app.UniPred_G,1)/5)*i,:);
                    testM = app.UniPred_M(1: floor(size(app.UniPred_G,1)/5)*i,:);

                    trainG = app.UniPred_G(floor(size(app.UniPred_G,1)/5)*i +1: end,:);
                    trainM = app.UniPred_M(floor(size(app.UniPred_G,1)/5)*i +1: end,:);
                else
                    testG = app.UniPred_G(floor(size(app.UniPred_G,1)/5)*(i-1) : floor(size(app.UniPred_G,1)/5)*i,:);
                    testM = app.UniPred_M(floor(size(app.UniPred_M,1)/5)*(i-1) : floor(size(app.UniPred_M,1)/5)*i,:);

                    trainG = app.UniPred_G([1: floor(size(app.UniPred_G,1)/5)*(i-1)-1, floor(size(app.UniPred_G,1)/5)*i:end],:);
                    trainM = app.UniPred_M([1: floor(size(app.UniPred_M,1)/5)*(i-1)-1, floor(size(app.UniPred_M,1)/5)*i:end],:);
                end

               [XL,yl,XS,YS,beta,PCTVAR,MSE,stats] = plsregress(trainG,trainM);
                yfit = [ones(size(testG,1),1) testG]*beta;
                ypred11 = [ypred11; yfit];
                testM1 = [testM1; testM];
            end
            
                app.Pred_Val = ypred11;
                app.Orig_Val = testM1;
        end

        function RF(app)
            % RF
            X = app.UniPred_G;
            y = app.UniPred_M;

            if size(y,2) == 1
                %Univariate
                RF_GM_1 = TreeBagger(250, X, y, Method = 'regression', OOBPrediction='on');
                app.Pred_Val = oobQuantilePredict(RF_GM_1);
                app.Orig_Val = y;
            else
                %Multivariate
                RF_GM_1 = TreeBagger(250, X, mean(y,2), Method = 'regression', OOBPrediction='on');
                app.Pred_Val = oobQuantilePredict(RF_GM_1);
                app.Orig_Val = mean(y,2);
            end


        end

        function LSTM(app)
            % LSTM

            %80:20 TRAINING:TESTING
            inputTrain = app.UniPred_G(1: floor(0.8*size(app.UniPred_G,1)),:);
            inputTest = app.UniPred_G(floor(0.8*size(app.UniPred_G,1))+1 : end,:);
            outputTrain = app.UniPred_M(1:floor(0.8*size(app.UniPred_M,1)),:);
            outputTest = app.UniPred_M(floor(0.8*size(app.UniPred_M,1))+1 : end,:);

            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            
            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            numResponses = 1;
            numHiddenUnits = 200;
            numFeatures = size(app.UniPred_G,2);
            layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(numHiddenUnits,'OutputMode','sequence')
                fullyConnectedLayer(50)
                dropoutLayer(0.5)
                fullyConnectedLayer(numResponses)
                regressionLayer];
            %
            % Training
            miniBatchSize = 25;
            options = trainingOptions('adam', ...
                'ExecutionEnvironment','cpu', ...
                'MaxEpochs',20, ...
                'MiniBatchSize',miniBatchSize, ...
                'GradientThreshold',2, ...
                'Shuffle','never',...
                'Verbose',0, ...
                'Plots','training-progress');

            
            if size(outputTrain,2) == 1
                %Univariate
                net = trainNetwork(inputTrain',outputTrain',layers,options);
            else
                %Multivariate
                net = trainNetwork(inputTrain',mean(outputTrain,2)',layers,options);
            end

            % Testing
            app.Pred_Val = predict(net,inputTest','MiniBatchSize',1);
            app.Pred_Val = app.Pred_Val';

            if size(outputTrain,2) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2);  %Multivariate
            end
        end

        function CNN(app)
            % CNN
            %80:20 TRAINING:TESTING
            inputTrain = app.UniPred_G(1: floor(0.8*size(app.UniPred_G,1)),:);
            inputTest = app.UniPred_G(floor(0.8*size(app.UniPred_G,1))+1 : end,:);
            outputTrain = app.UniPred_M(1:floor(0.8*size(app.UniPred_M,1)),:);
            outputTest = app.UniPred_M(floor(0.8*size(app.UniPred_M,1))+1 : end,:);

            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            
            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            numResponses = 1;
            numHiddenUnits = 200;
            numFeatures = size(app.UniPred_G,2);
            layers = [ ...
                sequenceInputLayer(numFeatures)
                convolution1dLayer(3,numFeatures,Padding="causal")
                reluLayer
                convolution1dLayer(3,numFeatures*2,Padding="same")
                reluLayer
                convolution1dLayer(3,numFeatures,Padding="same")
                reluLayer
                fullyConnectedLayer(250)
                fullyConnectedLayer(50)
                fullyConnectedLayer(numResponses)
                regressionLayer];
            %
            % Training
            miniBatchSize = 25;
            options = trainingOptions('adam', ...
                'ExecutionEnvironment','cpu', ...
                'MaxEpochs',20, ...
                'MiniBatchSize',miniBatchSize, ...
                'GradientThreshold',2, ...
                'Shuffle','never',...
                'Verbose',0, ...
                'Plots','training-progress');

            
            if size(outputTrain,2) == 1
                %Univariate
                net = trainNetwork(inputTrain',outputTrain',layers,options);
            else
                %Multivariate
                net = trainNetwork(inputTrain',mean(outputTrain'),layers,options);
            end

            % Testing
            app.Pred_Val = predict(net,inputTest','MiniBatchSize',1);
            app.Pred_Val = app.Pred_Val';

            if size(outputTrain,2) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2);  %Multivariate
            end
        end

        function UnivariatePrediction(app)
            
            app.UniPred_G = app.dataG_no(:,:)';
            app.UniPred_M = app.dataM_no(app.i_UniVarSample,:);
            app.UniPred_M = cell2mat(app.UniPred_M);
            app.UniPred_M = app.UniPred_M';
            if app.LASSOCheckBoxValue == 1
                %LASSO – univariate prediction
                LASSO(app) %Matlab code

                %Pythone code
                %pyrunfile('LASSO.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.BLUPCheckBoxValue == 1
                %BLUP – univariate prediction || GPR-Matlab!!!
                GPR(app) %Matlab code

                %Pythone code
                %pyrunfile('BLUP.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.SVRCheckBoxValue == 1
                %SVR – univariate prediction
                SVR(app) %Matlab code

                %Pythone code
                %pyrunfile('SVR.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.PLSCheckBoxValue == 1
                %PLS – univariate prediction
                PLS(app) %Matlab code

            elseif app.RandomForestCheckBoxValue == 1
                %RF – univariate prediction
                RF(app)

            elseif app.LSTMCheckBoxValue == 1
                %LSTM – univariate prediction
                LSTM(app)

            elseif app.CNNCheckBoxValue == 1
                %CNN– univariate prediction
                CNN(app)

            end
            ...
        end


        function MultivariatePrediction(app)
            
            app.UniPred_G = app.dataG_no(:,:)';
            app.UniPred_M = app.dataM_no(:,:);
            app.UniPred_M = cell2mat(app.UniPred_M);
            app.UniPred_M = app.UniPred_M';
            
            if app.LASSOCheckBoxValue == 1
                %LASSO 
                LASSO(app) %Matlab code

            elseif app.BLUPCheckBoxValue == 1
                %BLUP – multivariate prediction || GPR-Matlab!!!
                GPR(app) %Matlab code

            elseif app.SVRCheckBoxValue == 1
                %SVR – multivariate prediction
                SVR(app) %Matlab code

            elseif app.PLSCheckBoxValue == 1
                %PLS – multivariate prediction
                PLS(app) %Matlab code

            elseif app.RandomForestCheckBoxValue == 1
                %RF – multivariate prediction
                RF(app) %Matlab code

            elseif app.LSTMCheckBoxValue == 1
                %LSTM – multivariate prediction
                LSTM(app) %Matlab code

            elseif app.CNNCheckBoxValue == 1
                %CNN– univariate prediction
                CNN(app) %Matlab code  
            end
            ...
        end           
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Index initialization
            app.i_dotplotSample = 1;
            app.i_histplotSample = 1;
            app.i_UniVarSample = 1;
            app.LASSOCheckBoxValue = 1;
        end

        % Button pushed function: LoadGenomicInfoButton
        function LoadGenomicInfo(app, event)
                [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataG = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITableGen.Data = app.dataG;
                 varHeadTable = app.UITableGen.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableGen.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end                    
        end

        % Display data changed function: UITableGen
        function Table_G(app, event)
            updateTable(app);
        end

        % Button pushed function: LoadMetabolomeInfoButton
        function LoadMetabolomicInfo(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataM = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.dataM = rows2vars(app.dataM);
                 app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 app.dataM(1,:) = []; 
                 app.dataM_no = app.dataM(:, 2:end);
                 app.UITableMet.Data = app.dataM;
                 varHeadTable = app.UITableMet.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableMet.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Display data changed function: UITableMet
        function UITableMetDisplayDataChanged(app, event)
            newDisplayData = app.UITableMet.DisplayData;
            
        end

        % Button pushed function: DotplotButton
        function DotplotButtonPushed(app, event)
            app.dataG_no = table2array(app.dataG(:, 2:end));
            updateDotPlot(app);
        end

        % Button pushed function: HistogramButton
        function HistPlotButtonPushed(app, event)
            app.dataM_no = table2array(app.dataM(:, 2:end));
            updateHistPlot(app);
 
        end

        % Value changed function: DotPlotSampleSpinner
        function DotPlotSampler(app, event)
            app.i_dotplotSample = app.DotPlotSampleSpinner.Value;
            updateDotPlot(app);
        end

        % Value changed function: HistPlotSampleSpinner
        function HistPlotSampler(app, event)
            app.i_histplotSample = app.HistPlotSampleSpinner.Value;
            updateHistPlot(app);            
        end

        % Button pushed function: mapcaplotButton
        function MaPCAPloter(app, event)
            app.dataM_no = table2array(app.dataM(:, 2:end)); %Možná v toto je tu zbytečné
            mapcaplot(app.dataM_no)
        end

        % Value changed function: LASSOCheckBox
        function LassoCheckBox(app, event)
            app.LASSOCheckBoxValue = app.LASSOCheckBox.Value;

            if app.LASSOCheckBoxValue == 1
                app.BLUPCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0; 
            end
        end

        % Value changed function: GPRCheckBox
        function BlupCheckBox(app, event)
            app.BLUPCheckBoxValue = app.GPRCheckBox.Value;

           if app.BLUPCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
           end
           
        end

        % Value changed function: SVRCheckBox
        function SvrCheckBox(app, event)
            app.SVRCheckBoxValue = app.SVRCheckBox.Value;

            if app.SVRCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.BLUPCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            end
            
        end

        % Value changed function: PLSCheckBox
        function plsCheckBox(app, event)
             app.PLSCheckBoxValue = app.PLSCheckBox.Value;

             if app.PLSCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.BLUPCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
             end
            
        end

        % Value changed function: RandomForestCheckBox
        function RfCheckBox(app, event)
            app.RandomForestCheckBoxValue = app.RandomForestCheckBox.Value;
             
             if app.RandomForestCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.BLUPCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
             end
            
        end

        % Value changed function: LSTMCheckBox
        function lstmCheckBox(app, event)
            app.LSTMCheckBoxValue = app.LSTMCheckBox.Value;
            
            if app.LSTMCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.BLUPCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            end
            
        end

        % Value changed function: CNNCheckBox
        function cnnCheckBox(app, event)
             app.CNNCheckBoxValue = app.CNNCheckBox.Value;

             if app.CNNCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.BLUPCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
            end
            
        end

        % Value changed function: UniVarSample
        function UnivariateSampler(app, event)
            app.i_UniVarSample = app.UniVarSample.Value;
            
        end

        % Callback function: UniBoxPlot, UnivariatePredictionButton
        function UnivariatePred(app, event)
            app.dataG_no = table2array(app.dataG(:, 2:end));
            app.dataM_no = table2array(app.dataM(:, 2:end));

            app.i_UniVarSample = app.UniVarSample.Value;
            UnivariatePrediction(app);

           %MSE
           app.MSEEditField.Value = double(mse(app.Orig_Val,app.Pred_Val));
           %MAE
           app.MAEEditField.Value = double(mae(app.Orig_Val,app.Pred_Val));
           %CC
           app.CorrelationcoefEditField.Value = double(corr(app.Orig_Val,app.Pred_Val));
           %Boxplot
           boxplot(app.UniBoxPlot, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
        end

        % Button pushed function: SeeindetailButton
        function SeeInDetail_DotPlot(app, event)
            figure
            plot(app.dataG_no(:,app.i_dotplotSample),'o');
            xlabel('Genome sequence / Position')
            ylabel('SNPs')
            title('Dot plot')
        end

        % Button pushed function: SeeindetailButton_4
        function SeeInDetail_HistPlot(app, event)
            figure
            histogram(app.dataM_no(:,app.i_histplotSample));
            xlabel('Metabolites')
            title('Histogram')
       
        end

        % Button pushed function: MultivariatePredictionButton
        function MultiPred(app, event)
            app.dataG_no = table2array(app.dataG(:, 2:end));
            app.dataM_no = table2array(app.dataM(:, 2:end));

            MultivariatePrediction(app);

           %MSE
           app.MSEEditField_MultiVar.Value = double(mse(app.Orig_Val,app.Pred_Val));
           %MAE
           app.MAEEditField_MultiVar.Value = double(mae(app.Orig_Val,app.Pred_Val));
           %CC
           app.CorrelationcoefEditField_MultiVar.Value = double(mean2(corr(app.Orig_Val,app.Pred_Val)));
           %Boxplot
           if size(app.Orig_Val,2) == 1
            boxplot(app.MultiBoxPlot, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
           else
            boxplot(app.MultiBoxPlot, [mean(app.Orig_Val)',mean(app.Pred_Val)'],{'Original','Predicted'});
           end
        end

        % Button pushed function: RESETButton
        function ResetFcn(app, event)
            app.UIFigure.Visible = 'off'; % Open 2nd instance of app
            CageApp();    
            close(app.UIFigure) % Delete old instance

        end

        % Button pushed function: GWASButton_app
        function GWASButton_appPushed(app, event)
           GWAS(app,app.dataG,app.dataPhe)
        end

        % Button pushed function: LoadPhenotypeInfo
        function LoadPhenotypeInfoButtonPushed(app, event)
              [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataPhe = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITablePhe.Data = app.dataPhe;
                 varHeadTable = app.UITablePhe.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITablePhe.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Button pushed function: PRSButton_app
        function PRSButton_appPushed(app, event)
            PRS(app,app.dataG,app.dataPhe)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.Panel_5);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Feec_UBMI_Logo.PNG');
            app.Image = uiimage(app.Panel_5);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BUT_logo.png');
            app.Panel = uipanel(app.UIFigure);
            app.LoadGenomicInfoButton = uibutton(app.Panel, 'push');
            app.LoadGenomicInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGenomicInfo, true);
            app.LoadGenomicInfoButton.Text = 'Load Genomic Info';
            app.LoadMetabolomeInfoButton = uibutton(app.Panel, 'push');
            app.LoadMetabolomeInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMetabolomicInfo, true);
            app.LoadMetabolomeInfoButton.Text = 'Load Metabolome Info';
            app.UITableGen = uitable(app.Panel);
            app.UITableGen.ColumnName = '';
            app.UITableGen.ColumnRearrangeable = 'on';
            app.UITableGen.RowName = {};
            app.UITableGen.ColumnSortable = true;
            app.UITableGen.ColumnEditable = true;
            app.UITableGen.DisplayDataChangedFcn = createCallbackFcn(app, @Table_G, true);
            app.UITableMet = uitable(app.Panel);
            app.UITableMet.ColumnName = '';
            app.UITableMet.RowName = {};
            app.UITableMet.DisplayDataChangedFcn = createCallbackFcn(app, @UITableMetDisplayDataChanged, true);
            app.InputLabel = uilabel(app.Panel);
            app.InputLabel.Text = ' Input';
            app.LoadPhenotypeInfo = uibutton(app.Panel, 'push');
            app.LoadPhenotypeInfo.ButtonPushedFcn = createCallbackFcn(app, @LoadPhenotypeInfoButtonPushed, true);
            app.LoadPhenotypeInfo.Text = 'Load Phenotype Info';
            app.UITablePhe = uitable(app.Panel);
            app.UITablePhe.ColumnName = '';
            app.UITablePhe.RowName = {};
            app.Panel_3 = uipanel(app.UIFigure);
            app.LASSOCheckBox = uicheckbox(app.Panel_3);
            app.LASSOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LassoCheckBox, true);
            app.LASSOCheckBox.Text = 'LASSO';
            app.LASSOCheckBox.Value = true;
            app.SVRCheckBox = uicheckbox(app.Panel_3);
            app.SVRCheckBox.ValueChangedFcn = createCallbackFcn(app, @SvrCheckBox, true);
            app.SVRCheckBox.Text = 'SVR';
            app.LinearapproachLabel = uilabel(app.Panel_3);
            app.LinearapproachLabel.Text = 'Linear approach';
            app.NonlinearapproachLabel = uilabel(app.Panel_3);
            app.NonlinearapproachLabel.Text = 'Non-linear approach';
            app.DLapproachLabel = uilabel(app.Panel_3);
            app.DLapproachLabel.Text = 'DL approach';
            app.GPRCheckBox = uicheckbox(app.Panel_3);
            app.GPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlupCheckBox, true);
            app.GPRCheckBox.Text = 'GPR';
            app.PLSCheckBox = uicheckbox(app.Panel_3);
            app.PLSCheckBox.ValueChangedFcn = createCallbackFcn(app, @plsCheckBox, true);
            app.PLSCheckBox.Text = 'PLS';
            app.RandomForestCheckBox = uicheckbox(app.Panel_3);
            app.RandomForestCheckBox.ValueChangedFcn = createCallbackFcn(app, @RfCheckBox, true);
            app.RandomForestCheckBox.Text = 'RandomForest';
            app.LSTMCheckBox = uicheckbox(app.Panel_3);
            app.LSTMCheckBox.ValueChangedFcn = createCallbackFcn(app, @lstmCheckBox, true);
            app.LSTMCheckBox.Text = 'LSTM';
            app.CNNCheckBox = uicheckbox(app.Panel_3);
            app.CNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @cnnCheckBox, true);
            app.CNNCheckBox.Text = 'CNN';
            app.PRSButton_app = uibutton(app.Panel_3, 'push');
            app.PRSButton_app.ButtonPushedFcn = createCallbackFcn(app, @PRSButton_appPushed, true);
            app.PRSButton_app.Text = 'Polygenic Risk Score';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Dotplot = uiaxes(app.Panel_2);
            xlabel(app.Dotplot, 'Genome Sequence')
            ylabel(app.Dotplot, 'SNPs')
            zlabel(app.Dotplot, 'Z')
            app.Histplot = uiaxes(app.Panel_2);
            xlabel(app.Histplot, 'Metabolites Concentration')
            zlabel(app.Histplot, 'Z')
            app.VisualizationofinputdataLabel = uilabel(app.Panel_2);
            app.VisualizationofinputdataLabel.Text = ' Visualization of input data ';
            app.GenomicInfoLabel = uilabel(app.Panel_2);
            app.GenomicInfoLabel.Text = 'Genomic Info';
            app.MetaboliteInfoLabel = uilabel(app.Panel_2);
            app.MetaboliteInfoLabel.Text = 'Metabolite Info';
            app.mapcaplotButton = uibutton(app.Panel_2, 'push');
            app.mapcaplotButton.ButtonPushedFcn = createCallbackFcn(app, @MaPCAPloter, true);
            app.mapcaplotButton.Text = 'mapcaplot';
            app.SamplenoSpinner_2Label = uilabel(app.Panel_2);
            app.SamplenoSpinner_2Label.Text = 'Sample no.';
            app.HistPlotSampleSpinner = uispinner(app.Panel_2);
            app.HistPlotSampleSpinner.ValueChangedFcn = createCallbackFcn(app, @HistPlotSampler, true);
            app.HistPlotSampleSpinner.Value = 1;
            app.SamplenoSpinnerLabel = uilabel(app.Panel_2);
            app.SamplenoSpinnerLabel.Text = 'Sample no.';
            app.DotPlotSampleSpinner = uispinner(app.Panel_2);
            app.DotPlotSampleSpinner.ValueChangedFcn = createCallbackFcn(app, @DotPlotSampler, true);
            app.DotPlotSampleSpinner.Value = 1;
            app.SeeindetailButton = uibutton(app.Panel_2, 'push');
            app.SeeindetailButton.ButtonPushedFcn = createCallbackFcn(app, @SeeInDetail_DotPlot, true);
            app.SeeindetailButton.Text = 'See in detail';
            app.HistogramButton = uibutton(app.Panel_2, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistPlotButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.DotplotButton = uibutton(app.Panel_2, 'push');
            app.DotplotButton.ButtonPushedFcn = createCallbackFcn(app, @DotplotButtonPushed, true);
            app.DotplotButton.Text = 'Dot plot';
            app.SeeindetailButton_4 = uibutton(app.Panel_2, 'push');
            app.SeeindetailButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeeInDetail_HistPlot, true);
            app.SeeindetailButton_4.Text = 'See in detail';
            app.GWASButton_app = uibutton(app.Panel_2, 'push');
            app.GWASButton_app.ButtonPushedFcn = createCallbackFcn(app, @GWASButton_appPushed, true);
            app.GWASButton_app.Text = 'GWAS';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UniBoxPlot = uiaxes(app.Panel_4);
            zlabel(app.UniBoxPlot, 'Z')
            app.UniBoxPlot.ButtonDownFcn = createCallbackFcn(app, @UnivariatePred, true);
            app.MultiBoxPlot = uiaxes(app.Panel_4);
            xlabel(app.MultiBoxPlot, ' ')
            ylabel(app.MultiBoxPlot, ' ')
            zlabel(app.MultiBoxPlot, 'Z')
            app.PredictionsGenome2MetabolomeLabel = uilabel(app.Panel_4);
            app.PredictionsGenome2MetabolomeLabel.Text = 'Predictions Genome2Metabolome';
            app.UnivariatePredictionButton = uibutton(app.Panel_4, 'push');
            app.UnivariatePredictionButton.ButtonPushedFcn = createCallbackFcn(app, @UnivariatePred, true);
            app.UnivariatePredictionButton.Text = 'Univariate Prediction';
            app.MultivariatePredictionButton = uibutton(app.Panel_4, 'push');
            app.MultivariatePredictionButton.ButtonPushedFcn = createCallbackFcn(app, @MultiPred, true);
            app.MultivariatePredictionButton.Text = 'Multivariate Prediction';
            app.SamplenoSpinnerLabel_2 = uilabel(app.Panel_4);
            app.SamplenoSpinnerLabel_2.Text = 'Sample no.';
            app.UniVarSample = uispinner(app.Panel_4);
            app.UniVarSample.ValueChangedFcn = createCallbackFcn(app, @UnivariateSampler, true);
            app.UniVarSample.Value = 1;
            app.MSEEditField_3Label = uilabel(app.Panel_4);
            app.MSEEditField_3Label.Text = 'MSE';
            app.MSEEditField = uieditfield(app.Panel_4, 'numeric');
            app.MSEEditField_3Label_2 = uilabel(app.Panel_4);
            app.MSEEditField_3Label_2.Text = 'MAE';
            app.MAEEditField = uieditfield(app.Panel_4, 'numeric');
            app.CorrelationcoefEditField_3Label = uilabel(app.Panel_4);
            app.CorrelationcoefEditField_3Label.Text = 'Correlation coef';
            app.CorrelationcoefEditField = uieditfield(app.Panel_4, 'numeric');
            app.MSEEditField_3Label_3 = uilabel(app.Panel_4);
            app.MSEEditField_3Label_3.Text = 'MSE';
            app.MSEEditField_MultiVar = uieditfield(app.Panel_4, 'numeric');
            app.MAEEditField_2Label = uilabel(app.Panel_4);
            app.MAEEditField_2Label.Text = 'MAE';
            app.MAEEditField_MultiVar = uieditfield(app.Panel_4, 'numeric');
            app.CorrelationcoefEditField_2Label = uilabel(app.Panel_4);
            app.CorrelationcoefEditField_2Label.Text = 'Correlation coef';
            app.CorrelationcoefEditField_MultiVar = uieditfield(app.Panel_4, 'numeric');
            app.CaGeAppComputeraidedapproachforGenomicspredictionLabel = uilabel(app.UIFigure);
            app.CaGeAppComputeraidedapproachforGenomicspredictionLabel.Text = ' CaGe App (Computer-aided approach for Genomics prediction)';
            app.PredictionmethodsLabel = uilabel(app.UIFigure);
            app.PredictionmethodsLabel.Text = ' Prediction methods';
            app.VUT_logo = uiimage(app.UIFigure);
            app.VUT_logo.ImageSource = fullfile(pathToMLAPP, 'BUT_logo_T.png');
            app.MoSys_logo = uiimage(app.UIFigure);
            app.MoSys_logo.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFcn, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CageApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end