classdef GWAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        HelpButton          matlab.ui.control.Button
        ExampledataButton   matlab.ui.control.Button
        CalculateButton     matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        LoadSNPsInfoButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Image               matlab.ui.control.Image
        CaGeAppGWASLabel    matlab.ui.control.Label
        VUT_logo            matlab.ui.control.Image
    end

properties (Access = private)
        MainApp;
        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path
        Geno;        % Genomic data
        Phe;
        InfoSNP;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, geno_info, pheno_info)
            app.Geno = geno_info; % Genomic data
            app.Phe = pheno_info; % Phenotype data
            
            %Store main app in property
            app.MainApp = main_app;

        end

        % Button pushed function: LoadSNPsInfoButton
        function LoadSNPsInfoButtonPushed(app, event)
        [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 %toto je zde divne%%%
                 app.InfoSNP = readtable(strcat(app.rawFilePath,app.rawFileName));
                 %toto je zde divne%%%
                 app.UITable.Data = app.InfoSNP;
                 varHeadTable = app.UITable.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            %% treatment of the transposition matrix
             % Extracting column names from the app.Geno table and
             % preprocessed
            column_names = app.Geno.Properties.VariableNames;
            column_names(1) = [];
            text_column = app.Geno{:,1};
            text_column = cellstr(text_column);

            numeric_columns = app.Geno{:,2:end};
            transposed_numeric_data = numeric_columns';
            transposed_data = array2table(transposed_numeric_data);
            transposed_data.Properties.VariableNames = text_column';
            transposed_column_names = column_names';
            combined_data = [transposed_column_names, num2cell(transposed_numeric_data)];
            new = cell2table(combined_data);
            new.Properties.VariableNames = ['ColumnNames', text_column'];

        % waitbaru
    f = waitbar(0, 'Please wait...');
    waitbar(0.67, f, 'GWAS calculating..');

            %%
           
            % Setting column names
            new.Properties.VariableNames{1} = app.Phe.Properties.VariableNames{1};
            app.Phe.Properties.VariableNames{2} = 'phenotype';
            data = outerjoin(new, app.Phe, 'MergeKeys', true);
            
            % data pre-process/formatting
            genotype_data_columns = data.Properties.VariableNames;
            genotype_data_columns = genotype_data_columns(~strcmp(genotype_data_columns, data.Properties.VariableNames{1}));
            
            % inicialization of results table
            results = table();
            
            for i = 1:length(genotype_data_columns)
                snp = genotype_data_columns{i};
                x = data.(snp);
                y = data.phenotype;
                
                % add intercept into matrix
                data.Intercept = ones(size(x));
                data.SNP = x;
                
                % Fit mixed linear model
                formula = 'phenotype ~ SNP + (1|Intercept)';
                lme = fitlme(data, formula);
                
                % Získání odhadnutých koeficientů
                coef = fixedEffects(lme);
                beta = coef(2); % Odhadnutý koeficient pro SNP
                
                % Získání p-hodnoty
                stats = anova(lme, 'DFMethod', 'Satterthwaite');
                p_value = stats.pValue(2); % p-value pro efekt SNP
                
                % Uložení výsledků
                results = [results; table({snp}, p_value, beta, 'VariableNames', {'SNP', 'p_value', 'beta'})];
            end
            
            % Přidání informací o SNP a výpočty pro Manhattan plot
            app.InfoSNP.Properties.VariableNames{1} = 'SNP';
            results(end, :) = [];
            results.SNP = app.InfoSNP.SNP;
            manhattan_data = innerjoin(results(1:size(app.InfoSNP, 1), :), app.InfoSNP(:, {'SNP', 'Chromosome', 'Position'}), 'Keys', 'SNP');
            
            % Výpočet -log10(p-value)
            manhattan_data.NegLogP = -log10(manhattan_data.p_value);
            
            % Výpočet Bonferroniho korekce
            num_tests = length(genotype_data_columns);
            bonferroni_threshold = 0.05 / num_tests;
            bonferroni_threshold_log = -log10(bonferroni_threshold);
            
            % Plot Manhattan plot
            colors = hsv(length(unique(manhattan_data.Chromosome)));  % Define colors for each chromosome
            cumulative_max_position = 0;  % Initialize cumulative maximum position
            hold(app.UIAxes, 'on');
            for chr = unique(manhattan_data.Chromosome)'
                chr_data = manhattan_data(manhattan_data.Chromosome == chr, :);
                scatter(app.UIAxes, chr_data.Position + cumulative_max_position, chr_data.NegLogP, 20, colors(chr, :), 'filled', 'DisplayName', ['Chromosome ' num2str(chr)]);
                cumulative_max_position = cumulative_max_position + max(chr_data.Position);  % Update cumulative maximum position
            end
            
            % Plot Bonferroni correction line
            yline(app.UIAxes, bonferroni_threshold_log, 'r--', 'LineWidth', 2, 'DisplayName', 'Bonferroni Threshold');
            
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Genomic Position');
            ylabel(app.UIAxes, '-log10(P-Value)');
            title(app.UIAxes, 'Manhattan Plot for GWAS');
            legend(app.UIAxes, 'Location', 'bestoutside');

    
    % Uzavření waitbaru
    close(f);


        end

        % Button pushed function: ExampledataButton
        function ExampledataButtonPushed(app, event)
              try 
                 %toto je zde divne%%%
                 app.InfoSNP = readtable('input_example\GWAS_Ath\snp_data_with_locus_tag_FINAL.csv');
                 %toto je zde divne%%%
                 app.UITable.Data = app.InfoSNP;
                 varHeadTable = app.UITable.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VUT_logo = uiimage(app.UIFigure);
            app.VUT_logo.ImageSource = 'BUT_logo_T.png';
            app.CaGeAppGWASLabel = uilabel(app.UIFigure);
            app.CaGeAppGWASLabel.Text = ' CaGeApp – GWAS';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'GWAS')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadSNPsInfoButton = uibutton(app.Panel, 'push');
            app.LoadSNPsInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSNPsInfoButtonPushed, true);
            app.LoadSNPsInfoButton.Text = 'Load SNPs Info';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ExampledataButton = uibutton(app.Panel, 'push');
            app.ExampledataButton.ButtonPushedFcn = createCallbackFcn(app, @ExampledataButtonPushed, true);
            app.ExampledataButton.Text = 'Example data';
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GWAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end