classdef PANOMICapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image4                          matlab.ui.control.Image
        PreprocessingLabel              matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        HelpButton_2                    matlab.ui.control.Button
        AutomaticpreprocessingCheckBox  matlab.ui.control.CheckBox
        UploadpreprocesseddataCheckBox  matlab.ui.control.CheckBox
        PredictionapproachesLabel       matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        HelpButton_3                    matlab.ui.control.Button
        GenotypePhenotypeLabel          matlab.ui.control.Label
        PRSButton_app                   matlab.ui.control.Button
        GenomeMetabolomePhenotypeCheckBox  matlab.ui.control.CheckBox
        MetabolomePhenotypeCheckBox     matlab.ui.control.CheckBox
        GenomeMetabolomeCheckBox        matlab.ui.control.CheckBox
        PredictivemodellingLabel        matlab.ui.control.Label
        RESETButton                     matlab.ui.control.Button
        MoSys_logo                      matlab.ui.control.Image
        VUT_logo                        matlab.ui.control.Image
        PredictionmethodsLabel          matlab.ui.control.Label
        CaGeAppComputeraidedapproachforGenomicspredictionLabel  matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        HelpButton_6                    matlab.ui.control.Button
        MetaboliteEditField             matlab.ui.control.EditField
        MetaboliteEditFieldLabel        matlab.ui.control.Label
        CorrelationcoefEditField_MultiVar  matlab.ui.control.NumericEditField
        CorrelationcoefEditField_2Label  matlab.ui.control.Label
        MAEEditField_MultiVar           matlab.ui.control.NumericEditField
        MAEEditField_2Label             matlab.ui.control.Label
        MSEEditField_MultiVar           matlab.ui.control.NumericEditField
        MSEEditField_3Label_3           matlab.ui.control.Label
        CorrelationcoefEditField        matlab.ui.control.NumericEditField
        CorrelationcoefEditField_3Label  matlab.ui.control.Label
        MAEEditField                    matlab.ui.control.NumericEditField
        MSEEditField_3Label_2           matlab.ui.control.Label
        MSEEditField                    matlab.ui.control.NumericEditField
        MSEEditField_3Label             matlab.ui.control.Label
        UniVarSample                    matlab.ui.control.Spinner
        SamplenoSpinnerLabel_2          matlab.ui.control.Label
        multivariatepredictionButton    matlab.ui.control.Button
        univariatepredictionButton      matlab.ui.control.Button
        MultiBoxPlot                    matlab.ui.control.UIAxes
        UniBoxPlot                      matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        MetabolomeCheckBox              matlab.ui.control.CheckBox
        GenomeCheckBox                  matlab.ui.control.CheckBox
        HelpButton_5                    matlab.ui.control.Button
        GWASButton_app                  matlab.ui.control.Button
        SeeindetailButton_4             matlab.ui.control.Button
        PCAButton                       matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        SeeindetailButton               matlab.ui.control.Button
        HistPlotSampleSpinner           matlab.ui.control.Spinner
        SamplenoSpinner_2Label          matlab.ui.control.Label
        mapcaplotButton                 matlab.ui.control.Button
        HistogramMetaboliteInfoLabel    matlab.ui.control.Label
        PrincipleComponentAnalysisLabel  matlab.ui.control.Label
        VisualizationofinputdataLabel   matlab.ui.control.Label
        Histplot                        matlab.ui.control.UIAxes
        PCAplot                         matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        ParametersButton                matlab.ui.control.Button
        ENRCheckBox                     matlab.ui.control.CheckBox
        RRCheckBox                      matlab.ui.control.CheckBox
        HelpButton_4                    matlab.ui.control.Button
        CNNCheckBox                     matlab.ui.control.CheckBox
        LSTMCheckBox                    matlab.ui.control.CheckBox
        RandomForestCheckBox            matlab.ui.control.CheckBox
        PLSCheckBox                     matlab.ui.control.CheckBox
        GPRCheckBox                     matlab.ui.control.CheckBox
        DLapproachLabel                 matlab.ui.control.Label
        NonlinearapproachLabel          matlab.ui.control.Label
        LinearapproachLabel             matlab.ui.control.Label
        SVRCheckBox                     matlab.ui.control.CheckBox
        LASSOCheckBox                   matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        LoadExampleData_PheInfo         matlab.ui.control.Button
        LoadExampleData_MetInfo         matlab.ui.control.Button
        LoadExampleData_GenoInfo        matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SeeindetailButton_PheInfo       matlab.ui.control.Button
        SeeindetailButton_MetInfo       matlab.ui.control.Button
        SeeindetailButton_GenoInfo      matlab.ui.control.Button
        UITablePhe                      matlab.ui.control.Table
        LoadPhenotypeInfo               matlab.ui.control.Button
        InputLabel                      matlab.ui.control.Label
        UITableMet                      matlab.ui.control.Table
        UITableGen                      matlab.ui.control.Table
        LoadMetabolomeInfoButton        matlab.ui.control.Button
        LoadGenomicInfoButton           matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        MoSys_logo_2                    matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path
        dataG;        % Genomic data
        dataG_no;     % Genomic data – only number
        dataM;        % Metabolomic data
        dataM_no;     % Metabolomic data – only number
        dataPhe;      % Phenotype data
        dataPhe_no;     % Phenotype data – only number    

        automatic_preprocessed_value;
        already_preprocessed_value;

        x_input; %input in predictions modelling
        y_output; %output in predictions modelling

        i_dotplotSample; i_histplotSample; % Selected sample for visualization

        %Check Box Vars 
        LASSOCheckBoxValue; RRCheckBoxValue; ENRCheckBoxValue; 
        GPRCheckBoxValue; SVRCheckBoxValue;
        PLSCheckBoxValue; RandomForestCheckBoxValue; LSTMCheckBoxValue; 
        CNNCheckBoxValue; CheckBoxSum;

        GenomeMetabolomeCheckBoxValue;
        MetabolomePhenotypeCheckBoxValue;
        GenomeMetabolomePhenotypeCheckBoxValue;

        GenomeCheckBoxValue; MetabolomeCheckBoxValue;

        %Univariate vars__________________________________________________
        %Selected metabolites for prediction
        i_UniVarSample; UniPred_G; UniPred_M; UniPred_P;
        %_________________________________________________________________


        Pred_Val; %Predictied values
        Orig_Val; %Originals values

        %Outputs vars
        OutputMSE; OutputMAE;

        %VAR - waitingbar
        f;


        %_________________________________________________________________
        number_of_decisionsTrees_input;
        max_epoches_LSTM_input;
        max_epoches_CNN_input;
        gradientTreshold_LSTM_input;
        gradientTreshold_CNN_input; 

    end
    
    methods (Access = private)
        
        function updateTable(app)
            % Get Table UI component data
            app.dataG.Data = app.UITableGen.DisplayData;            
            app.dataM.Data = app.UITableMet.DisplayData; 
        end

        function updatePCAPlot(app)

            if app.GenomeCheckBoxValue == 1
            app.dataG_no = table2array(app.dataG(:, 2:end));
            %plot(app.PCAplot, app.dataG_no(:,app.i_dotplotSample),'o');
            [~, score, ~, ~, ~, ~] = pca(app.dataG_no);

            scatter(app.PCAplot,score(:,1), score(:,2));
            else
            app.dataM_no = table2array(app.dataM(:, 2:end));
            [~, score, ~, ~, ~, ~] = pca(cell2mat(app.dataM_no));
            scatter(app.PCAplot,score(:,1), score(:,2)); 
            end


        end

        function updateHistPlot(app)
            histogram(app.Histplot,cell2mat(app.dataM_no(:,app.i_histplotSample)'));
        end


        function ENR(app)
            % ENR prediction approach:
            X = app.x_input;
            y = app.y_output;

            waitbar(.67,app.f,'Prediction modelling..');

            n = length(y);
            c = cvpartition(n,'HoldOut',0.3);
            idxTrain = training(c,1);
            idxTest = ~idxTrain;
            XTrain = X(idxTrain,:);
            yTrain = y(idxTrain);
            XTest = X(idxTest,:);
            yTest = y(idxTest);
            [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',0.75,'CV',5); %cross-val = 5
            idxLambda1SE = FitInfo.Index1SE;
            coef = B(:,idxLambda1SE);
            coef0 = FitInfo.Intercept(idxLambda1SE);

            app.Pred_Val = XTest*coef + coef0;
            app.Orig_Val = yTest;

            waitbar(.90,app.f,'Prediction modelling..');
        end


        function LASSO(app)
            % LASSO prediction approach:
            X = app.x_input;
            y = app.y_output;

            waitbar(.67,app.f,'Prediction modelling..');

            n = length(y);
            c = cvpartition(n,'HoldOut',0.3);
            idxTrain = training(c,1);
            idxTest = ~idxTrain;
            XTrain = X(idxTrain,:);
            yTrain = y(idxTrain);
            XTest = X(idxTest,:);
            yTest = y(idxTest);
            [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',1,'CV',5); %cross-val = 5
            idxLambda1SE = FitInfo.Index1SE;
            coef = B(:,idxLambda1SE);
            coef0 = FitInfo.Intercept(idxLambda1SE);

            app.Pred_Val = XTest*coef + coef0;
            app.Orig_Val = yTest;

            waitbar(.90,app.f,'Prediction modelling..');
        end

        function RR(app)
            % RR prediction approach:
            X = app.x_input;
            y = app.y_output;

            waitbar(.67,app.f,'Prediction modelling..');

            n = length(y);
            c = cvpartition(n,'HoldOut',0.3);
            idxTrain = training(c,1);
            idxTest = ~idxTrain;
            XTrain = X(idxTrain,:);
            yTrain = y(idxTrain);
            XTest = X(idxTest,:);
            yTest = y(idxTest);
            [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',0.000001,'CV',5); %cross-val = 5
            idxLambda1SE = FitInfo.Index1SE;
            coef = B(:,idxLambda1SE);
            coef0 = FitInfo.Intercept(idxLambda1SE);

            app.Pred_Val = XTest*coef + coef0;
            app.Orig_Val = yTest;

            waitbar(.90,app.f,'Prediction modelling..');
        end

        function GPR(app)
            %Fit a Gaussian process regression (GPR) model

            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            waitbar(.67,app.f,'Prediction modelling..');

            for i=1:5
                if i == 1
                    testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                    testM = app.y_output(1: floor(size(app.x_input,1)/5)*i,:);

                    trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                    trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                else
                    testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                    testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);

                    trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                    trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                end
                X = trainG;
                Y = trainM;

                if size(Y,2) == 1
                %Univariate
                    gprMdl1 = fitrgp(X,Y);
                    x = testG;
                    [ypred1,~,~] = predict(gprMdl1,x);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; testM];
                else
                %Multivariate
                    gprMdl1 = fitrgp(X,mean(Y,2));
                    x = testG;
                    [ypred1,~,~] = predict(gprMdl1,x);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; mean(testM,2)];
                end

            end
            
                app.Pred_Val = ypred11;
                app.Orig_Val = testM1;

            %80:20 TRAINING:TESTING
            %trainG = app.UniPred_G(1: floor(0.8*size(app.UniPred_G,1)),:);
            %testG = app.UniPred_G(floor(0.8*size(app.UniPred_G,1))+1 : end,:);
            %trainM = app.UniPred_M(1: floor(0.8*size(app.UniPred_M,1)),:);
            %testM = app.UniPred_M(floor(0.8*size(app.UniPred_M,1))+1 : end,:);
            %
            %X = trainG;
            %Y = trainM;
            %gprMdl1 = fitrgp(X,Y);
            %
            %x = testG;
            %[ypred1,~,~] = predict(gprMdl1,x);
            %
            %app.Pred_Val = ypred1;
            %app.Orig_Val = testM;

            waitbar(.90,app.f,'Prediction modelling..');

        end

        function SVR(app)
            % SVR
            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            waitbar(.67,app.f,'Prediction modelling..');

            for i=1:5
                if i == 1
                    testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                    testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);

                    trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                    trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                else
                    testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                    testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);

                    trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                    trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                end
                X = trainG;
                Y = trainM;

                if size(Y,2) == 1
                %Univariate
                    MdlLin = fitrsvm(X,Y);
                    ypred1 = predict(MdlLin,testG);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; testM];
                else
                %Multivariate
                    MdlLin = fitrsvm(X,mean(Y,2));
                    ypred1 = predict(MdlLin,testG);

                    ypred11 = [ypred11; ypred1];
                    testM1 = [testM1; mean(testM,2)];
                end

            end
            
                app.Pred_Val = ypred11;
                app.Orig_Val = testM1;

            %80:20 TRAINING:TESTING
            %trainG = app.UniPred_G(1: floor(0.8*size(app.UniPred_G,1)),: );
            %testG = app.UniPred_G(floor(0.8*size(app.UniPred_G,1))+1 : end,:);
            %trainM = app.UniPred_M(1: floor(0.8*size(app.UniPred_M,1)),: );
            %testM = app.UniPred_M(floor(0.8*size(app.UniPred_M,1))+1 : end,:);
            %    
            %X = trainG;
            %Y = trainM;
            %
            %MdlLin = fitrsvm(X,Y);
            %
            %app.Pred_Val = predict(MdlLin,testG);
            %app.Orig_Val = testM;

            waitbar(.90,app.f,'Prediction modelling..');
        end

        function PLS(app)
            % PLS
            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            waitbar(.67,app.f,'Prediction modelling..');

            for i=1:5
                if i == 1
                    testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                    testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);

                    trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                    trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                else
                    testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                    testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);

                    trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                    trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                end

               [XL,yl,XS,YS,beta,PCTVAR,MSE,stats] = plsregress(trainG,trainM);
                yfit = [ones(size(testG,1),1) testG]*beta;
                ypred11 = [ypred11; yfit];
                testM1 = [testM1; testM];
            end
            
                app.Pred_Val = ypred11;
                app.Orig_Val = testM1;

            waitbar(.90,app.f,'Prediction modelling..');
        end

        function RF(app)
            % RF
            X = app.x_input;
            y = app.y_output;

            waitbar(.67,app.f,'Prediction modelling..');

            if size(y,2) == 1
                %Univariate
                RF_GM_1 = TreeBagger(app.number_of_decisionsTrees_input, X, y, Method = 'regression', OOBPrediction='on');
                app.Pred_Val = oobQuantilePredict(RF_GM_1);
                app.Orig_Val = y;
            else
                %Multivariate
                RF_GM_1 = TreeBagger(250, X, mean(y,2), Method = 'regression', OOBPrediction='on');
                app.Pred_Val = oobQuantilePredict(RF_GM_1);
                app.Orig_Val = mean(y,2);
            end

            waitbar(.90,app.f,'Prediction modelling..');
        end

        function LSTM(app)
            % LSTM

            waitbar(.67,app.f,'Prediction modelling..');

            %80:20 TRAINING:TESTING
            inputTrain = app.x_input(1: floor(0.8*size(app.x_input,1)),:);
            inputTest = app.x_input(floor(0.8*size(app.x_input,1))+1 : end,:);
            outputTrain = app.y_output(1:floor(0.8*size(app.y_output,1)),:);
            outputTest = app.y_output(floor(0.8*size(app.y_output,1))+1 : end,:);

            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            
            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            numResponses = 1;
            numHiddenUnits = 200;
            numFeatures = size(app.x_input,2);
            layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(numHiddenUnits,'OutputMode','sequence')
                fullyConnectedLayer(50)
                dropoutLayer(0.5)
                fullyConnectedLayer(numResponses)
                regressionLayer];
            %
            % Training
            miniBatchSize = 25;

            if gpuDeviceCount > 0
                 options = trainingOptions('adam', ...
                    'ExecutionEnvironment','gpu', ...
                    'MaxEpochs',app.max_epoches_LSTM_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_LSTM_input, ...
                    'Shuffle','never',...
                    'Verbose',0);
            else 
                options = trainingOptions('adam', ...
                    'ExecutionEnvironment','cpu', ...
                    'MaxEpochs',app.max_epoches_LSTM_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_LSTM_input, ...
                    'Shuffle','never',...
                    'Verbose',0);
            end

            
            if size(outputTrain,1) == 1
                %Univariate
                net = trainNetwork(inputTrain',outputTrain',layers,options);
            else
                %Multivariate
                net = trainNetwork(inputTrain',mean(outputTrain,2)',layers,options);
            end

            % Testing
            app.Pred_Val = predict(net,inputTest','MiniBatchSize',1);
            app.Pred_Val = app.Pred_Val';

            if size(outputTrain,1) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2);  %Multivariate
            end
            
            waitbar(.90,app.f,'Prediction modelling..');
        end

        function CNN(app)
            % CNN

            waitbar(.67,app.f,'Prediction modelling..');

            %80:20 TRAINING:TESTING
            inputTrain = app.x_input(1: floor(0.8*size(app.x_input,1)),:);
            inputTest = app.x_input(floor(0.8*size(app.x_input,1))+1 : end,:);
            outputTrain = app.y_output(1:floor(0.8*size(app.y_output,1)),:);
            outputTest = app.y_output(floor(0.8*size(app.y_output,1))+1 : end,:);

            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            
            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            numResponses = 1;
            numHiddenUnits = 200;
            numFeatures = size(app.x_input,2);
            layers = [ ...
                sequenceInputLayer(numFeatures)
                convolution1dLayer(3,numFeatures,Padding="causal")
                reluLayer
                convolution1dLayer(3,numFeatures*2,Padding="same")
                reluLayer
                convolution1dLayer(3,numFeatures,Padding="same")
                reluLayer
                fullyConnectedLayer(250)
                fullyConnectedLayer(50)
                fullyConnectedLayer(numResponses)
                regressionLayer];
            %
            % Training
            miniBatchSize = 25;

            if gpuDeviceCount > 0
                 options = trainingOptions('adam', ...
                    'ExecutionEnvironment','gpu', ...
                    'MaxEpochs',app.max_epoches_CNN_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_CNN_input, ...
                    'Shuffle','never',...
                    'Verbose',0);
            else 
                options = trainingOptions('adam', ...
                    'ExecutionEnvironment','cpu', ...
                    'MaxEpochs',app.max_epoches_CNN_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_CNN_input, ...
                    'Shuffle','never',...
                    'Verbose',0);
            end
           
            if size(outputTrain,2) == 1
                %Univariate
                net = trainNetwork(inputTrain',outputTrain',layers,options);
            else
                %Multivariate
                net = trainNetwork(inputTrain',mean(outputTrain'),layers,options);
            end

            % Testing
            app.Pred_Val = predict(net,inputTest','MiniBatchSize',1);
            app.Pred_Val = app.Pred_Val';

            if size(outputTrain,2) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2);  %Multivariate
            end

            waitbar(.90,app.f,'Prediction modelling..');
        end

        function UnivariatePrediction(app)
            
            
            app.UniPred_G = app.dataG_no(:,:)';
            app.UniPred_M = app.dataM_no(app.i_UniVarSample,:);
            app.UniPred_M = cell2mat(app.UniPred_M);
            app.UniPred_M = app.UniPred_M';

            if ~isempty(app.dataPhe)
                app.dataPhe_no = table2cell(app.dataPhe(:, 2:end));
                app.dataPhe_no = cell2mat(app.dataPhe_no);
                app.UniPred_P = app.dataPhe_no;
            end

            % -- input G->M (so far)
            if app.GenomeMetabolomeCheckBoxValue == 1
                app.x_input = app.UniPred_G;
                app.y_output = app.UniPred_M;
            elseif app.MetabolomePhenotypeCheckBoxValue == 1
                app.x_input = app.UniPred_M;
                app.y_output = app.UniPred_P;
            elseif app.GenomeMetabolomePhenotypeCheckBoxValue == 1
                app.x_input = [app.UniPred_G,app.UniPred_M];
                app.y_output = app.UniPred_P;
            end


            % waitbaru
            app.f = waitbar(0,'Please wait...');
            pause(.5)

            waitbar(.33,app.f,'Prediction modelling..');


            if app.LASSOCheckBoxValue == 1
                %LASSO 
                LASSO(app) %Matlab code

           elseif app.RRCheckBoxValue == 1
                %RR – univariate prediction
                RR(app) %Matlab code

            elseif app.ENRCheckBoxValue == 1
                %ENR – univariate prediction 
                ENR(app) %Matlab code

            elseif app.GPRCheckBoxValue == 1
                %GPR – univariate prediction || GPR-Matlab!!!
                GPR(app) %Matlab code

                %Pythone code
                %pyrunfile('GPR.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.SVRCheckBoxValue == 1
                %SVR – univariate prediction
                SVR(app) %Matlab code

                %Pythone code
                %pyrunfile('SVR.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.PLSCheckBoxValue == 1
                %PLS – univariate prediction
                PLS(app) %Matlab code

            elseif app.RandomForestCheckBoxValue == 1
                %RF – univariate prediction
                RF(app)

            elseif app.LSTMCheckBoxValue == 1
                %LSTM – univariate prediction
                LSTM(app)

            elseif app.CNNCheckBoxValue == 1
                %CNN– univariate prediction
                CNN(app)

            end
            ...

           % Close waitbaru
           close(app.f);
        end


        function MultivariatePrediction(app)
            
            app.UniPred_G = app.dataG_no(:,:)';
            app.UniPred_M = app.dataM_no(:,:);
            app.UniPred_M = cell2mat(app.UniPred_M);
            app.UniPred_M = app.UniPred_M';


            if ~isempty(app.dataPhe)
            app.dataPhe_no = table2cell(app.dataPhe(:, 2:end));
            app.dataPhe_no = cell2mat(app.dataPhe_no);
            app.UniPred_P = app.dataPhe_no';
            end

            % -- input G->M (so far)
            if app.GenomeMetabolomeCheckBoxValue == 1
                app.x_input = app.UniPred_G;
                app.y_output = app.UniPred_M;
            elseif app.MetabolomePhenotypeCheckBoxValue == 1
                app.x_input = app.UniPred_M;
                app.y_output = app.UniPred_P';
            elseif app.GenomeMetabolomePhenotypeCheckBoxValue == 1
                app.x_input = [app.UniPred_G,app.UniPred_M];
                app.y_output = app.UniPred_P;
            end
            
            % waitbaru
            app.f = waitbar(0, 'Please wait...');
            waitbar(0.80, app.f, 'Prediction modelling..');

            if app.LASSOCheckBoxValue == 1
                %LASSO 
                LASSO(app) %Matlab code

           elseif app.RRCheckBoxValue == 1
                %RR – multivariate prediction
                RR(app) %Matlab code

            elseif app.ENRCheckBoxValue == 1
                %ENR – multivariate prediction 
                ENR(app) %Matlab code

            elseif app.GPRCheckBoxValue == 1
                %GPR – multivariate prediction || GPR-Matlab!!!
                GPR(app) %Matlab code

            elseif app.SVRCheckBoxValue == 1
                %SVR – multivariate prediction
                SVR(app) %Matlab code

            elseif app.PLSCheckBoxValue == 1
                %PLS – multivariate prediction
                PLS(app) %Matlab code

            elseif app.RandomForestCheckBoxValue == 1
                %RF – multivariate prediction
                RF(app) %Matlab code

            elseif app.LSTMCheckBoxValue == 1
                %LSTM – multivariate prediction
                LSTM(app) %Matlab code

            elseif app.CNNCheckBoxValue == 1
                %CNN– univariate prediction
                CNN(app) %Matlab code  
            end
            ...

             % Close waitbaru
            close(app.f);

        end           
        
    end
    
    methods (Access = public)
        
        function update_parameters(app, number_of_decisionsTrees, max_epoches_LSTM, max_epoches_CNN, gradientTreshold_LSTM, gradientTreshold_CNN)
            app.number_of_decisionsTrees_input = number_of_decisionsTrees;
            app.max_epoches_LSTM_input = max_epoches_LSTM;
            app.max_epoches_CNN_input = max_epoches_CNN;
            app.gradientTreshold_LSTM_input = gradientTreshold_LSTM;
            app.gradientTreshold_CNN_input = gradientTreshold_CNN;  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Index initialization
            app.i_dotplotSample = 1;
            app.i_histplotSample = 1;
            app.i_UniVarSample = 1;
            app.LASSOCheckBoxValue = 1;
            app.GenomeMetabolomeCheckBoxValue = 1;
            app.GenomeCheckBoxValue = 1;
            app.already_preprocessed_value = 1;

            app.dataG = [];
            app.dataM = [];
            app.dataPhe = [];

            app.number_of_decisionsTrees_input = 250;
            app.max_epoches_LSTM_input = 20;
            app.max_epoches_CNN_input = 20;
            app.gradientTreshold_LSTM_input = 2;
            app.gradientTreshold_CNN_input = 2; 


        end

        % Button pushed function: LoadGenomicInfoButton
        function LoadGenomicInfo(app, event)
                [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataG = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITableGen.Data = app.dataG;
                 varHeadTable = app.UITableGen.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableGen.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end                    
        end

        % Display data changed function: UITableGen
        function Table_G(app, event)
            updateTable(app);
        end

        % Button pushed function: LoadMetabolomeInfoButton
        function LoadMetabolomicInfo(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataM = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.dataM = rows2vars(app.dataM);
                 app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 app.dataM(1,:) = []; 
                 app.dataM_no = app.dataM(:, 2:end);
                 app.UITableMet.Data = app.dataM;
                 varHeadTable = app.UITableMet.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableMet.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end

            var_metabolite = app.dataM(1,1);
            var_metabolite_to_cell = var_metabolite{1,1};
            app.MetaboliteEditField.Value = var_metabolite_to_cell{1};
        end

        % Display data changed function: UITableMet
        function UITableMetDisplayDataChanged(app, event)
            newDisplayData = app.UITableMet.DisplayData;
            
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            updatePCAPlot(app);
        end

        % Button pushed function: HistogramButton
        function HistPlotButtonPushed(app, event)
            app.dataM_no = table2array(app.dataM(:, 2:end));
            updateHistPlot(app);
 
        end

        % Value changed function: HistPlotSampleSpinner
        function HistPlotSampler(app, event)
            app.i_histplotSample = app.HistPlotSampleSpinner.Value;
            updateHistPlot(app);            
        end

        % Button pushed function: mapcaplotButton
        function MaPCAPloter(app, event)
            app.dataM_no = table2array(app.dataM(:, 2:end)); %Možná v toto je tu zbytečné
            mapcaplot(app.dataM_no)
        end

        % Value changed function: LASSOCheckBox
        function LassoCheckBox(app, event)
            app.LASSOCheckBoxValue = app.LASSOCheckBox.Value;

            if app.LASSOCheckBoxValue == 1
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0; 
            end
        end

        % Value changed function: SVRCheckBox
        function SvrCheckBox(app, event)
            app.SVRCheckBoxValue = app.SVRCheckBox.Value;

            if app.SVRCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            end
            
        end

        % Value changed function: PLSCheckBox
        function plsCheckBox(app, event)
             app.PLSCheckBoxValue = app.PLSCheckBox.Value;

             if app.PLSCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
             end
            
        end

        % Value changed function: RandomForestCheckBox
        function RfCheckBox(app, event)
            app.RandomForestCheckBoxValue = app.RandomForestCheckBox.Value;
             
             if app.RandomForestCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
             end
            
        end

        % Value changed function: LSTMCheckBox
        function lstmCheckBox(app, event)
            app.LSTMCheckBoxValue = app.LSTMCheckBox.Value;
            
            if app.LSTMCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            end
            
        end

        % Value changed function: CNNCheckBox
        function cnnCheckBox(app, event)
             app.CNNCheckBoxValue = app.CNNCheckBox.Value;

             if app.CNNCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
            end
            
        end

        % Value changed function: GPRCheckBox
        function GPRCheckBoxValueChanged(app, event)
            app.GPRCheckBoxValue = app.GPRCheckBox.Value;

           if app.GPRCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
           end
            
        end

        % Value changed function: RRCheckBox
        function RRCheckBoxValueChanged(app, event)
             app.RRCheckBoxValue = app.RRCheckBox.Value;

             if app.RRCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
            end
            

        end

        % Value changed function: ENRCheckBox
        function ENRCheckBoxValueChanged(app, event)
             app.ENRCheckBoxValue = app.ENRCheckBox.Value;

             if app.ENRCheckBoxValue == 1
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
            end
            
        end

        % Value changed function: MetaboliteEditField, UniVarSample
        function UnivariateSampler(app, event)
            
                        % -- input G->M (so far)
            if app.GenomeMetabolomeCheckBoxValue == 1
                app.i_UniVarSample = app.UniVarSample.Value;
                var_metabolite = app.dataM(app.i_UniVarSample,1);
                var_metabolite_to_cell = var_metabolite{1,1};
                app.MetaboliteEditField.Value = var_metabolite_to_cell{1};

                 app.MetaboliteEditFieldLabel.Text = 'Metabolite';

            elseif app.MetabolomePhenotypeCheckBoxValue == 1
                app.i_UniVarSample = app.UniVarSample.Value;
                var_pheno = app.dataPhe.Properties.VariableNames(app.i_UniVarSample+1);
                var_pheno_to_cell = var_pheno{1,1};
                app.MetaboliteEditField.Value = var_pheno_to_cell;
                
                app.MetaboliteEditFieldLabel.Text = 'Phenotype';

            elseif app.GenomeMetabolomePhenotypeCheckBoxValue == 1
                app.i_UniVarSample = app.UniVarSample.Value;
                var_pheno = app.dataPhe.Properties.VariableNames(app.i_UniVarSample+1);
                var_pheno_to_cell = var_pheno{1,1};
                app.MetaboliteEditField.Value = var_pheno_to_cell;
                
                app.MetaboliteEditFieldLabel.Text = 'Phenotype';


            end


        end

        % Callback function: UniBoxPlot, univariatepredictionButton
        function UnivariatePred(app, event)
            app.dataG_no = table2array(app.dataG(:, 2:end));
            app.dataM_no = table2array(app.dataM(:, 2:end));

            app.i_UniVarSample = app.UniVarSample.Value;
            UnivariatePrediction(app);

           %MSE
           app.MSEEditField.Value = double(mse(app.Orig_Val,app.Pred_Val));
           %MAE
           app.MAEEditField.Value = double(mae(app.Orig_Val,app.Pred_Val));
           %CC
           if app.MetabolomePhenotypeCheckBoxValue == 1
               if isnan(corr(app.Orig_Val,app.Pred_Val))
                app.CorrelationcoefEditField.Value = 0;
               else
                app.CorrelationcoefEditField.Value = double(corr(app.Orig_Val,app.Pred_Val));
               end
               app.Pred_Val= app.Pred_Val;
           else
            if app.PLSCheckBoxValue == 1
                app.CorrelationcoefEditField.Value = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
            else
                app.CorrelationcoefEditField.Value = double(corr(app.Orig_Val,app.Pred_Val));
            end
           end
           %Boxplot
%           if app.MetabolomePhenotypeCheckBoxValue == 1
%            boxplot(app.UniBoxPlot, [app.Orig_Val',app.Pred_Val'],{'Original','Predicted'});
%           else
            boxplot(app.UniBoxPlot, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
%           end
        end

        % Button pushed function: SeeindetailButton
        function SeeInDetail_DotPlot(app, event)
            if app.GenomeCheckBoxValue == 1
                figure
                [~, score, ~, ~, ~, ~] = pca(app.dataG_no);
                plot(score(:,1), score(:,2), 'o');
                hold on
                % Optionally, you can customize the plot (title, labels, etc.)
                title('PCA');
                xlabel('PC1');
                ylabel('PC2');
                hold off
            else
                figure
                [~, score, ~, ~, ~, ~] = pca(cell2mat(app.dataM_no));
                plot(score(:,1), score(:,2), 'o');
                hold on
                % Optionally, you can customize the plot (title, labels, etc.)
                title('PCA');
                xlabel('PC1');
                ylabel('PC2');
                hold off
            end 


        end

        % Button pushed function: SeeindetailButton_4
        function SeeInDetail_HistPlot(app, event)
            figure
            histogram(cell2mat(app.dataM_no(:,app.i_histplotSample)'));
            xlabel('Metabolites')
            title('Histogram')
       
        end

        % Button pushed function: multivariatepredictionButton
        function MultiPred(app, event)
            app.dataG_no = table2array(app.dataG(:, 2:end));
            app.dataM_no = table2array(app.dataM(:, 2:end));
           
            UniPred_M_var = app.dataM_no(:,:);
            UniPred_M_var = cell2mat(UniPred_M_var);
            UniPred_M_var = UniPred_M_var';

            if ~isempty(app.dataPhe)
            dataPhe_no_var = table2cell(app.dataPhe(:, 2:end));
            dataPhe_no_var = cell2mat(dataPhe_no_var);
            UniPred_P_var = dataPhe_no_var';
            end

            % -- input G->M (so far)
            if app.GenomeMetabolomeCheckBoxValue == 1
                y_output_var = UniPred_M_var;
            elseif app.MetabolomePhenotypeCheckBoxValue == 1
                y_output_var = UniPred_P_var;
            elseif app.GenomeMetabolomePhenotypeCheckBoxValue == 1
                y_output_var = UniPred_P_var;
            end

           if size(y_output_var,1) == 1
                %If you have only one column in Phenotype – do UnivPred
                app.i_UniVarSample = app.UniVarSample.Value;
                UnivariatePrediction(app);
    
                %MSE
                app.MSEEditField_MultiVar.Value = double(mse(app.Orig_Val,app.Pred_Val));
                %MAE
                app.MAEEditField_MultiVar.Value = double(mae(app.Orig_Val,app.Pred_Val));
                %CC
                if app.MetabolomePhenotypeCheckBoxValue == 1
                    if isnan(corr(app.Orig_Val,app.Pred_Val))
                        app.CorrelationcoefEditField_MultiVar.Value = 0;
                    else
                        app.CorrelationcoefEditField_MultiVar.Value = double(corr(app.Orig_Val,app.Pred_Val));
                    end
                    app.Pred_Val= app.Pred_Val;
                else
                    if app.PLSCheckBoxValue == 1
                        app.CorrelationcoefEditField_MultiVar.Value = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                    else
                        app.CorrelationcoefEditField_MultiVar.Value = double(corr(app.Orig_Val,app.Pred_Val));
                    end
                end
                %Boxplot
                %if app.MetabolomePhenotypeCheckBoxValue == 1
                %    boxplot(app.MultiBoxPlot, [app.Orig_Val',app.Pred_Val'],{'Original','Predicted'});
                %else
                    boxplot(app.MultiBoxPlot, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
                %end

         else
                MultivariatePrediction(app);
    
               %MSE
               app.MSEEditField_MultiVar.Value = double(mse(app.Orig_Val,app.Pred_Val));
               %MAE
               app.MAEEditField_MultiVar.Value = double(mae(app.Orig_Val,app.Pred_Val));
               %CC
               if app.PLSCheckBoxValue == 1
                app.CorrelationcoefEditField_MultiVar.Value = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
               else
                if size(app.Orig_Val,1) == 1
                    app.CorrelationcoefEditField_MultiVar.Value = double(corr(app.Orig_Val',app.Pred_Val));
                else
                    app.CorrelationcoefEditField_MultiVar.Value = double(corr(app.Orig_Val,app.Pred_Val));
                end
               end
               %Boxplot
               if size(app.Orig_Val,2) == 1
                  boxplot(app.MultiBoxPlot, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
               else
                  boxplot(app.MultiBoxPlot, [mean(app.Orig_Val)',mean(app.Pred_Val)'],{'Original','Predicted'});
               end
           end


        end

        % Button pushed function: RESETButton
        function ResetFcn(app, event)
            app.UIFigure.Visible = 'off'; % Open 2nd instance of app
            PANOMICapp();    
            close(app.UIFigure) % Delete old instance

        end

        % Button pushed function: GWASButton_app
        function GWASButton_appPushed(app, event)
           GWAS(app,app.dataG,app.dataPhe)
        end

        % Button pushed function: LoadPhenotypeInfo
        function LoadPhenotypeInfoButtonPushed(app, event)
              [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataPhe = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITablePhe.Data = app.dataPhe;
                 varHeadTable = app.UITablePhe.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 %app.dataPhe(1,:) = []; 
                 app.dataPhe_no = app.dataPhe(:, 2:end);
                 %app.dataPhe_no = app.dataPhe_no';
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITablePhe.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Button pushed function: PRSButton_app
        function PRSButton_appPushed(app, event)
            PRS(app,app.dataG,app.dataPhe)
        end

        % Value changed function: GenomeMetabolomeCheckBox
        function GenomeMetabolomeCheckBoxValueChanged(app, event)
          app.GenomeMetabolomeCheckBoxValue = app.GenomeMetabolomeCheckBox.Value;

            if app.GenomeMetabolomeCheckBoxValue == 1
                app.MetabolomePhenotypeCheckBoxValue = 0; app.MetabolomePhenotypeCheckBox.Value = 0;
                app.GenomeMetabolomePhenotypeCheckBoxValue = 0; app.GenomeMetabolomePhenotypeCheckBox.Value = 0;
                
                app.LASSOCheckBoxValue = 1; app.LASSOCheckBox.Value = 1;
                app.LASSOCheckBox.Enable ="on";
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.LASSOCheckBox.Enable ="on";
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.ENRCheckBox.Enable ="on";
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.GPRCheckBox.Enable ="on";
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.SVRCheckBox.Enable ="on";
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.PLSCheckBox.Enable ="on";
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            
            end

            UnivariateSampler(app)
        end

        % Value changed function: MetabolomePhenotypeCheckBox
        function MetabolomePhenotypeCheckBoxValueChanged(app, event)
            app.MetabolomePhenotypeCheckBoxValue = app.MetabolomePhenotypeCheckBox.Value;

            if app.MetabolomePhenotypeCheckBoxValue == 1
                app.GenomeMetabolomeCheckBoxValue = 0; app.GenomeMetabolomeCheckBox.Value = 0;
                app.GenomeMetabolomePhenotypeCheckBoxValue = 0; app.GenomeMetabolomePhenotypeCheckBox.Value = 0;
            
                app.LASSOCheckBoxValue = 1; app.LASSOCheckBox.Value = 1;
                app.LASSOCheckBox.Enable ="on";
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.RRCheckBox.Enable ="on";
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.ENRCheckBox.Enable ="on";
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.GPRCheckBox.Enable ="on";
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.SVRCheckBox.Enable ="on";
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.PLSCheckBox.Enable ="on";
                app.RandomForestCheckBoxValue = 0; app.RandomForestCheckBox.Value = 0;
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            end

            UnivariateSampler(app)
        end

        % Value changed function: GenomeMetabolomePhenotypeCheckBox
        function GenomeMetabolomePhenotypeCheckBoxValueChanged(app, event)
            app.GenomeMetabolomePhenotypeCheckBoxValue = app.GenomeMetabolomePhenotypeCheckBox.Value;

            if app.GenomeMetabolomePhenotypeCheckBoxValue == 1
                app.GenomeMetabolomeCheckBoxValue = 0; app.GenomeMetabolomeCheckBox.Value = 0;
                app.MetabolomePhenotypeCheckBoxValue = 0; app.MetabolomePhenotypeCheckBox.Value = 0;

                app.RandomForestCheckBoxValue = 1; app.RandomForestCheckBox.Value = 1;
                app.LASSOCheckBoxValue = 0; app.LASSOCheckBox.Value = 0;
                app.LASSOCheckBox.Enable ="off";
                app.RRCheckBoxValue = 0; app.RRCheckBox.Value = 0;
                app.RRCheckBox.Enable ="off";
                app.ENRCheckBoxValue = 0; app.ENRCheckBox.Value = 0;
                app.ENRCheckBox.Enable ="off";
                app.GPRCheckBoxValue = 0; app.GPRCheckBox.Value = 0;
                app.GPRCheckBox.Enable ="off";
                app.SVRCheckBoxValue = 0; app.SVRCheckBox.Value = 0;
                app.SVRCheckBox.Enable ="off";
                app.PLSCheckBoxValue = 0; app.PLSCheckBox.Value = 0;
                app.PLSCheckBox.Enable ="off";
                app.LSTMCheckBoxValue = 0; app.LSTMCheckBox.Value = 0;
                app.CNNCheckBoxValue = 0; app.CNNCheckBox.Value = 0;
            end

            UnivariateSampler(app)
        end

        % Value changed function: UploadpreprocesseddataCheckBox
        function UploadpreprocesseddataCheckBoxValueChanged(app, event)
           app.already_preprocessed_value = app.UploadpreprocesseddataCheckBox.Value;

           if app.already_preprocessed_value == 1
                app.automatic_preprocessed_value = 0; app.AutomaticpreprocessingCheckBox.Value = 0;
           end

        end

        % Value changed function: AutomaticpreprocessingCheckBox
        function AutomaticpreprocessingCheckBoxValueChanged(app, event)
            app.automatic_preprocessed_value = app.AutomaticpreprocessingCheckBox.Value;

           if app.automatic_preprocessed_value == 1
                app.already_preprocessed_value = 0; app.UploadpreprocesseddataCheckBox.Value = 0;
           end

           if size(app.dataG,2) == size(app.dataM,2) && (size(app.dataM,2)-1) == size(app.dataPhe,1)
           else 
            %Error - oprav data tak aby se shodovali počty sloupců apod.. 
            uialert(app.UIFigure,'Check your dataset size',"Invalid File","Interpreter","html");
           end

            % waitbaru

            app.f = waitbar(0,'Please wait...');
            pause(.5)

            waitbar(.33, app.f, 'Pre-processing data – Genomics Data');

           % genomics data _______________________________________________      
            for i=2:size(app.dataG,2)
                for j=1:size(app.dataG,1)
                    if isnan(table2array(app.dataG(j,i)))
                        app.dataG(j,i)=0;
                    end
                end
            end

            waitbar(.50,app.f,'Processing your data – Metabolomics Data');
            
            % metabolomics data __________________________________________
            for i=1:size(app.dataM,1)
                for j=2:size(app.dataM,2)
                    var1 = table2array(app.dataM(i,2:end));
                    var2 = cell2mat(var1);
                    cellVar = app.dataM{i,j};
                    cellVar{1}=min(var2)/2;
                    app.dataM{i,j} = cellVar;
                end
            end
            
            for j=1:size(app.dataM,1)
                met = app.dataM{j,2:end};
                met_log = log10(abs(cell2mat(met)));
                
                for i = 2:(size(app.dataM,2)-1)
                    cellVar_Met = app.dataM{j,i};
                    cellVar_Met{1}=met_log(1,i);
                    app.dataM{j,i} = cellVar_Met;
                end
            end
            
            waitbar(.90,app.f,'Processing your data – Phenotypic Data');
            % phenotype data _____________________________________________
            for i=1:size(app.dataPhe,1)
                for j=2:size(app.dataPhe,2)
                    if isnan(table2array(app.dataPhe(i,j)))
                        app.dataPhe(i,j)=0;
                    end
                end
            end

            %close waitbar
            close(app.f);

        end

        % Button pushed function: LoadExampleData_GenoInfo
        function LoadExampleData_GenoInfoButtonPushed(app, event)
              try 
                 app.dataG = readtable('input_example\input_G_M_Phe\Genotype_Ath.csv');
                 app.UITableGen.Data = app.dataG;
                 varHeadTable = app.UITableGen.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableGen.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end

        end

        % Button pushed function: LoadExampleData_MetInfo
        function LoadExampleData_MetInfoButtonPushed(app, event)
              try 
                 app.dataM = readtable('input_example\input_G_M_Phe\Metabolites_Ath_6C.csv');
                 app.dataM = rows2vars(app.dataM);
                 app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 app.dataM(1,:) = []; 
                 app.dataM_no = app.dataM(:, 2:end);
                 app.UITableMet.Data = app.dataM;
                 varHeadTable = app.UITableMet.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableMet.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            
            var_metabolite = app.dataM(1,1);
            var_metabolite_to_cell = var_metabolite{1,1};
            app.MetaboliteEditField.Value = var_metabolite_to_cell{1};
        end

        % Button pushed function: LoadExampleData_PheInfo
        function LoadExampleData_PheInfoButtonPushed(app, event)

              try 
                 app.dataPhe = readtable('input_example\input_G_M_Phe\Phenotype_Ath_RGR_6.csv');
                 app.UITablePhe.Data = app.dataPhe;
                 varHeadTable = app.UITablePhe.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 %app.dataPhe(1,:) = []; 
                 app.dataPhe_no = app.dataPhe(:, 2:end);
                 %app.dataPhe_no = app.dataPhe_no';
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITablePhe.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end

        end

        % Button pushed function: SeeindetailButton_GenoInfo
        function SeeindetailButton_GenoInfoPushed(app, event)
                % Create a new figure for detailed view
                detailedFig = uifigure('Name', 'Table in Detail', 'Position', [100 100 600 400]);
                
                % Create a UITable in the new figure
                detailedTable = uitable(detailedFig);
                
                % Set the data of the detailedTable to be the same as app.UITable
                detailedTable.Data = app.UITableGen.Data;
                
                % Adjust the position and size of the detailedTable
                detailedTable.Position = [20 20 560 360];
                
                % Optionally, set column names if not automatically set
                detailedTable.ColumnName = app.UITableGen.ColumnName;
        end

        % Button pushed function: SeeindetailButton_MetInfo
        function SeeindetailButton_MetInfoPushed(app, event)
                % Create a new figure for detailed view
                detailedFig = uifigure('Name', 'Table in Detail', 'Position', [100 100 600 400]);
                
                % Create a UITable in the new figure
                detailedTable = uitable(detailedFig);
                
                % Set the data of the detailedTable to be the same as app.UITable
                detailedTable.Data = app.UITableMet.Data;
                
                % Adjust the position and size of the detailedTable
                detailedTable.Position = [20 20 560 360];
                
                % Optionally, set column names if not automatically set
                detailedTable.ColumnName = app.UITableMet.ColumnName;
        end

        % Button pushed function: SeeindetailButton_PheInfo
        function SeeindetailButton_PheInfoPushed(app, event)
                            % Create a new figure for detailed view
                detailedFig = uifigure('Name', 'Table in Detail', 'Position', [100 100 600 400]);
                
                % Create a UITable in the new figure
                detailedTable = uitable(detailedFig);
                
                % Set the data of the detailedTable to be the same as app.UITable
                detailedTable.Data = app.UITablePhe.Data;
                
                % Adjust the position and size of the detailedTable
                detailedTable.Position = [20 20 560 360];
                
                % Optionally, set column names if not automatically set
                detailedTable.ColumnName = app.UITablePhe.ColumnName;
        end

        % Value changed function: GenomeCheckBox
        function GenomeCheckBoxValueChanged(app, event)
            app.GenomeCheckBoxValue = app.GenomeCheckBox.Value;

            if app.GenomeCheckBoxValue == 1
                app.MetabolomeCheckBoxValue = 0; app.MetabolomeCheckBox.Value = 0;            
            end
        end

        % Value changed function: MetabolomeCheckBox
        function MetabolomeCheckBoxValueChanged(app, event)
            app.MetabolomeCheckBoxValue = app.MetabolomeCheckBox.Value;

            if app.MetabolomeCheckBoxValue == 1
                app.GenomeCheckBoxValue = 0; app.GenomeCheckBox.Value = 0;            
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Input', ...
                'This section allows you to upload and specify the data files needed for analysis. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), metabolomic data (metabolite concentrations), and phenotypic data (trait measurements). Properly format your data according to the provided templates to avoid errors during processing.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle));
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    msgbox(helpText{index + 1}, sectionTitle, 'help');
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Input');  % Replace 'Input' with the title of the section you want help for 
        end

        % Button pushed function: HelpButton_5
        function HelpButton_5Pushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                 'Visualization of Input Data', ...
                 'Use this section to preview and examine your uploaded data. Visualizations include summary statistics, distributions, and initial data plots to help you understand the structure and quality of your input data. This step is crucial for verifying that your data is correctly formatted and ready for further analysis.', ...
                };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle));
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    msgbox(helpText{index + 1}, sectionTitle, 'help');
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Visualization of Input Data');  % Replace 'Input' with the title of the section you want help for 
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
              helpText = {
                'Pre-processing', ...
                    'Here, you can prepare your data for analysis by performing tasks such as data normalization, missing value imputation, and feature selection. Pre-processing ensures that your data is clean and suitable for accurate modeling. Follow the steps to handle anomalies and prepare your dataset according to the specific needs of your analysis.', ...
                   };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle));
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    msgbox(helpText{index + 1}, sectionTitle, 'help');
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Pre-processing');
        end

        % Button pushed function: HelpButton_3
        function HelpButton_3Pushed(app, event)
           helpText = {
                'Prediction Approaches', ...
    'This section allows you to select from various prediction methods tailored to your data. You can choose linear models (e.g., LASSO, Ridge Regression, Elastic Net), non-linear models (e.g., Random Forest), or deep learning approaches (e.g., LSTM, CNN). Each approach has its own strengths and is suited for different types of predictive tasks. Select the method that best fits your data and analysis goals.', ...
                };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle));
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    msgbox(helpText{index + 1}, sectionTitle, 'help');
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Prediction Approaches');
        end

        % Button pushed function: HelpButton_4
        function HelpButton_4Pushed(app, event)
                       helpText = {
               'Prediction Methods', ...
    'Here, you configure and apply your chosen prediction methods to your data. Options include linear regression, support vector regression, and advanced techniques like Gaussian Process Regression. Customize your model settings, perform cross-validation, and evaluate the performance of each method to ensure optimal predictions. Detailed instructions for each method are provided within this section.', ...
             };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle));
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    msgbox(helpText{index + 1}, sectionTitle, 'help');
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Prediction Methods');
        end

        % Button pushed function: HelpButton_6
        function HelpButton_6Pushed(app, event)
                                   helpText = {
               'Predictive Modelling', ...
    'This final step involves building and validating your predictive models. Use the configured methods to train models on your data, assess their performance using metrics such as accuracy or mean squared error, and visualize the results. This section provides tools to fine-tune your models and interpret their outputs, helping you achieve reliable and actionable predictions.'
};
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle));
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    msgbox(helpText{index + 1}, sectionTitle, 'help');
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Predictive Modelling');
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            Parameters(app, app.number_of_decisionsTrees_input, app.max_epoches_LSTM_input, app.max_epoches_CNN_input, app.gradientTreshold_LSTM_input, app.gradientTreshold_CNN_input)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.Panel_5);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Feec_UBMI_Logo.PNG');
            app.Image = uiimage(app.Panel_5);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BUT_logo.png');
            app.Image3 = uiimage(app.Panel_5);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Vime_logo.png');
            app.MoSys_logo_2 = uiimage(app.Panel_5);
            app.MoSys_logo_2.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.Panel = uipanel(app.UIFigure);
            app.LoadGenomicInfoButton = uibutton(app.Panel, 'push');
            app.LoadGenomicInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGenomicInfo, true);
            app.LoadGenomicInfoButton.Text = 'Load Genomic Info';
            app.LoadMetabolomeInfoButton = uibutton(app.Panel, 'push');
            app.LoadMetabolomeInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMetabolomicInfo, true);
            app.LoadMetabolomeInfoButton.Text = 'Load Metabolome Info';
            app.UITableGen = uitable(app.Panel);
            app.UITableGen.ColumnName = '';
            app.UITableGen.ColumnRearrangeable = 'on';
            app.UITableGen.RowName = {};
            app.UITableGen.ColumnSortable = true;
            app.UITableGen.ColumnEditable = true;
            app.UITableGen.DisplayDataChangedFcn = createCallbackFcn(app, @Table_G, true);
            app.UITableMet = uitable(app.Panel);
            app.UITableMet.ColumnName = '';
            app.UITableMet.RowName = {};
            app.UITableMet.DisplayDataChangedFcn = createCallbackFcn(app, @UITableMetDisplayDataChanged, true);
            app.InputLabel = uilabel(app.Panel);
            app.InputLabel.Text = ' 1] Input';
            app.LoadPhenotypeInfo = uibutton(app.Panel, 'push');
            app.LoadPhenotypeInfo.ButtonPushedFcn = createCallbackFcn(app, @LoadPhenotypeInfoButtonPushed, true);
            app.LoadPhenotypeInfo.Text = 'Load Phenotype Info';
            app.UITablePhe = uitable(app.Panel);
            app.UITablePhe.ColumnName = '';
            app.UITablePhe.RowName = {};
            app.SeeindetailButton_GenoInfo = uibutton(app.Panel, 'push');
            app.SeeindetailButton_GenoInfo.ButtonPushedFcn = createCallbackFcn(app, @SeeindetailButton_GenoInfoPushed, true);
            app.SeeindetailButton_GenoInfo.Text = 'See in detail';
            app.SeeindetailButton_MetInfo = uibutton(app.Panel, 'push');
            app.SeeindetailButton_MetInfo.ButtonPushedFcn = createCallbackFcn(app, @SeeindetailButton_MetInfoPushed, true);
            app.SeeindetailButton_MetInfo.Text = 'See in detail';
            app.SeeindetailButton_PheInfo = uibutton(app.Panel, 'push');
            app.SeeindetailButton_PheInfo.ButtonPushedFcn = createCallbackFcn(app, @SeeindetailButton_PheInfoPushed, true);
            app.SeeindetailButton_PheInfo.Text = 'See in detail';
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.LoadExampleData_GenoInfo = uibutton(app.Panel, 'push');
            app.LoadExampleData_GenoInfo.ButtonPushedFcn = createCallbackFcn(app, @LoadExampleData_GenoInfoButtonPushed, true);
            app.LoadExampleData_GenoInfo.Text = 'Example data';
            app.LoadExampleData_MetInfo = uibutton(app.Panel, 'push');
            app.LoadExampleData_MetInfo.ButtonPushedFcn = createCallbackFcn(app, @LoadExampleData_MetInfoButtonPushed, true);
            app.LoadExampleData_MetInfo.Text = 'Example data';
            app.LoadExampleData_PheInfo = uibutton(app.Panel, 'push');
            app.LoadExampleData_PheInfo.ButtonPushedFcn = createCallbackFcn(app, @LoadExampleData_PheInfoButtonPushed, true);
            app.LoadExampleData_PheInfo.Text = 'Example data';
            app.Panel_3 = uipanel(app.UIFigure);
            app.LASSOCheckBox = uicheckbox(app.Panel_3);
            app.LASSOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LassoCheckBox, true);
            app.LASSOCheckBox.Text = 'LASSO';
            app.LASSOCheckBox.Value = true;
            app.SVRCheckBox = uicheckbox(app.Panel_3);
            app.SVRCheckBox.ValueChangedFcn = createCallbackFcn(app, @SvrCheckBox, true);
            app.SVRCheckBox.Text = 'SVR';
            app.LinearapproachLabel = uilabel(app.Panel_3);
            app.LinearapproachLabel.Text = 'Linear approach';
            app.NonlinearapproachLabel = uilabel(app.Panel_3);
            app.NonlinearapproachLabel.Text = 'Non-linear approach';
            app.DLapproachLabel = uilabel(app.Panel_3);
            app.DLapproachLabel.Text = 'DL approach';
            app.GPRCheckBox = uicheckbox(app.Panel_3);
            app.GPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPRCheckBoxValueChanged, true);
            app.GPRCheckBox.Text = 'GPR';
            app.PLSCheckBox = uicheckbox(app.Panel_3);
            app.PLSCheckBox.ValueChangedFcn = createCallbackFcn(app, @plsCheckBox, true);
            app.PLSCheckBox.Text = 'PLS';
            app.RandomForestCheckBox = uicheckbox(app.Panel_3);
            app.RandomForestCheckBox.ValueChangedFcn = createCallbackFcn(app, @RfCheckBox, true);
            app.RandomForestCheckBox.Text = 'RandomForest';
            app.LSTMCheckBox = uicheckbox(app.Panel_3);
            app.LSTMCheckBox.ValueChangedFcn = createCallbackFcn(app, @lstmCheckBox, true);
            app.LSTMCheckBox.Text = 'LSTM';
            app.CNNCheckBox = uicheckbox(app.Panel_3);
            app.CNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @cnnCheckBox, true);
            app.CNNCheckBox.Text = 'CNN';
            app.HelpButton_4 = uibutton(app.Panel_3, 'push');
            app.HelpButton_4.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_4Pushed, true);
            app.HelpButton_4.Text = 'Help';
            app.RRCheckBox = uicheckbox(app.Panel_3);
            app.RRCheckBox.ValueChangedFcn = createCallbackFcn(app, @RRCheckBoxValueChanged, true);
            app.RRCheckBox.Text = 'RR';
            app.ENRCheckBox = uicheckbox(app.Panel_3);
            app.ENRCheckBox.ValueChangedFcn = createCallbackFcn(app, @ENRCheckBoxValueChanged, true);
            app.ENRCheckBox.Text = 'ENR';
            app.ParametersButton = uibutton(app.Panel_3, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.Panel_2 = uipanel(app.UIFigure);
            app.PCAplot = uiaxes(app.Panel_2);
            xlabel(app.PCAplot, 'PC1')
            ylabel(app.PCAplot, 'PC2')
            zlabel(app.PCAplot, 'Z')
            app.Histplot = uiaxes(app.Panel_2);
            xlabel(app.Histplot, 'Metabolites Concentration')
            zlabel(app.Histplot, 'Z')
            app.VisualizationofinputdataLabel = uilabel(app.Panel_2);
            app.VisualizationofinputdataLabel.Text = ' 2] Visualization of input data ';
            app.PrincipleComponentAnalysisLabel = uilabel(app.Panel_2);
            app.PrincipleComponentAnalysisLabel.Text = 'Principle Component Analysis';
            app.HistogramMetaboliteInfoLabel = uilabel(app.Panel_2);
            app.HistogramMetaboliteInfoLabel.Text = 'Histogram – Metabolite Info';
            app.mapcaplotButton = uibutton(app.Panel_2, 'push');
            app.mapcaplotButton.ButtonPushedFcn = createCallbackFcn(app, @MaPCAPloter, true);
            app.mapcaplotButton.Text = 'mapcaplot';
            app.SamplenoSpinner_2Label = uilabel(app.Panel_2);
            app.SamplenoSpinner_2Label.Text = 'Sample no.';
            app.HistPlotSampleSpinner = uispinner(app.Panel_2);
            app.HistPlotSampleSpinner.ValueChangedFcn = createCallbackFcn(app, @HistPlotSampler, true);
            app.HistPlotSampleSpinner.Value = 1;
            app.SeeindetailButton = uibutton(app.Panel_2, 'push');
            app.SeeindetailButton.ButtonPushedFcn = createCallbackFcn(app, @SeeInDetail_DotPlot, true);
            app.SeeindetailButton.Text = 'See in detail';
            app.HistogramButton = uibutton(app.Panel_2, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistPlotButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.PCAButton = uibutton(app.Panel_2, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA';
            app.SeeindetailButton_4 = uibutton(app.Panel_2, 'push');
            app.SeeindetailButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeeInDetail_HistPlot, true);
            app.SeeindetailButton_4.Text = 'See in detail';
            app.GWASButton_app = uibutton(app.Panel_2, 'push');
            app.GWASButton_app.ButtonPushedFcn = createCallbackFcn(app, @GWASButton_appPushed, true);
            app.GWASButton_app.Text = 'GWAS';
            app.HelpButton_5 = uibutton(app.Panel_2, 'push');
            app.HelpButton_5.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_5Pushed, true);
            app.HelpButton_5.Text = 'Help';
            app.GenomeCheckBox = uicheckbox(app.Panel_2);
            app.GenomeCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenomeCheckBoxValueChanged, true);
            app.GenomeCheckBox.Text = 'Genome';
            app.GenomeCheckBox.Value = true;
            app.MetabolomeCheckBox = uicheckbox(app.Panel_2);
            app.MetabolomeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MetabolomeCheckBoxValueChanged, true);
            app.MetabolomeCheckBox.Text = 'Metabolome';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UniBoxPlot = uiaxes(app.Panel_4);
            zlabel(app.UniBoxPlot, 'Z')
            app.UniBoxPlot.ButtonDownFcn = createCallbackFcn(app, @UnivariatePred, true);
            app.MultiBoxPlot = uiaxes(app.Panel_4);
            xlabel(app.MultiBoxPlot, ' ')
            ylabel(app.MultiBoxPlot, ' ')
            zlabel(app.MultiBoxPlot, 'Z')
            app.univariatepredictionButton = uibutton(app.Panel_4, 'push');
            app.univariatepredictionButton.ButtonPushedFcn = createCallbackFcn(app, @UnivariatePred, true);
            app.univariatepredictionButton.Text = {'univariate'; 'prediction'};
            app.multivariatepredictionButton = uibutton(app.Panel_4, 'push');
            app.multivariatepredictionButton.ButtonPushedFcn = createCallbackFcn(app, @MultiPred, true);
            app.multivariatepredictionButton.Text = {'multivariate '; 'prediction'};
            app.SamplenoSpinnerLabel_2 = uilabel(app.Panel_4);
            app.SamplenoSpinnerLabel_2.Text = 'Sample no.';
            app.UniVarSample = uispinner(app.Panel_4);
            app.UniVarSample.ValueChangedFcn = createCallbackFcn(app, @UnivariateSampler, true);
            app.UniVarSample.Value = 1;
            app.MSEEditField_3Label = uilabel(app.Panel_4);
            app.MSEEditField_3Label.Text = 'MSE';
            app.MSEEditField = uieditfield(app.Panel_4, 'numeric');
            app.MSEEditField_3Label_2 = uilabel(app.Panel_4);
            app.MSEEditField_3Label_2.Text = 'MAE';
            app.MAEEditField = uieditfield(app.Panel_4, 'numeric');
            app.CorrelationcoefEditField_3Label = uilabel(app.Panel_4);
            app.CorrelationcoefEditField_3Label.Text = 'Correlation coef';
            app.CorrelationcoefEditField = uieditfield(app.Panel_4, 'numeric');
            app.MSEEditField_3Label_3 = uilabel(app.Panel_4);
            app.MSEEditField_3Label_3.Text = 'MSE';
            app.MSEEditField_MultiVar = uieditfield(app.Panel_4, 'numeric');
            app.MAEEditField_2Label = uilabel(app.Panel_4);
            app.MAEEditField_2Label.Text = 'MAE';
            app.MAEEditField_MultiVar = uieditfield(app.Panel_4, 'numeric');
            app.CorrelationcoefEditField_2Label = uilabel(app.Panel_4);
            app.CorrelationcoefEditField_2Label.Text = 'Correlation coef';
            app.CorrelationcoefEditField_MultiVar = uieditfield(app.Panel_4, 'numeric');
            app.MetaboliteEditFieldLabel = uilabel(app.Panel_4);
            app.MetaboliteEditFieldLabel.Text = 'Metabolite';
            app.MetaboliteEditField = uieditfield(app.Panel_4, 'text');
            app.MetaboliteEditField.ValueChangedFcn = createCallbackFcn(app, @UnivariateSampler, true);
            app.MetaboliteEditField.Editable = 'off';
            app.HelpButton_6 = uibutton(app.Panel_4, 'push');
            app.HelpButton_6.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_6Pushed, true);
            app.HelpButton_6.Text = 'Help';
            app.CaGeAppComputeraidedapproachforGenomicspredictionLabel = uilabel(app.UIFigure);
            app.CaGeAppComputeraidedapproachforGenomicspredictionLabel.Text = ' PANOMICs App (Computer-aided approach from Genomics to panOMICs predictions)';
            app.PredictionmethodsLabel = uilabel(app.UIFigure);
            app.PredictionmethodsLabel.Text = ' 5] Prediction methods';
            app.VUT_logo = uiimage(app.UIFigure);
            app.VUT_logo.ImageSource = fullfile(pathToMLAPP, 'BUT_logo_T.png');
            app.MoSys_logo = uiimage(app.UIFigure);
            app.MoSys_logo.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFcn, true);
            app.RESETButton.Text = 'RESET';
            app.PredictivemodellingLabel = uilabel(app.UIFigure);
            app.PredictivemodellingLabel.Text = ' 6] Predictive modelling';
            app.Panel_6 = uipanel(app.UIFigure);
            app.GenomeMetabolomeCheckBox = uicheckbox(app.Panel_6);
            app.GenomeMetabolomeCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenomeMetabolomeCheckBoxValueChanged, true);
            app.GenomeMetabolomeCheckBox.Text = 'Genome  –> Metabolome';
            app.GenomeMetabolomeCheckBox.Value = true;
            app.MetabolomePhenotypeCheckBox = uicheckbox(app.Panel_6);
            app.MetabolomePhenotypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MetabolomePhenotypeCheckBoxValueChanged, true);
            app.MetabolomePhenotypeCheckBox.Text = 'Metabolome –> Phenotype';
            app.GenomeMetabolomePhenotypeCheckBox = uicheckbox(app.Panel_6);
            app.GenomeMetabolomePhenotypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenomeMetabolomePhenotypeCheckBoxValueChanged, true);
            app.GenomeMetabolomePhenotypeCheckBox.Text = 'Genome + Metabolome –> Phenotype';
            app.PRSButton_app = uibutton(app.Panel_6, 'push');
            app.PRSButton_app.ButtonPushedFcn = createCallbackFcn(app, @PRSButton_appPushed, true);
            app.PRSButton_app.Text = 'Polygenic Risk Score';
            app.GenotypePhenotypeLabel = uilabel(app.Panel_6);
            app.GenotypePhenotypeLabel.Text = 'Genotype -> Phenotype';
            app.HelpButton_3 = uibutton(app.Panel_6, 'push');
            app.HelpButton_3.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_3Pushed, true);
            app.HelpButton_3.Text = 'Help';
            app.PredictionapproachesLabel = uilabel(app.UIFigure);
            app.PredictionapproachesLabel.Text = ' 4] Prediction approaches';
            app.Panel_7 = uipanel(app.UIFigure);
            app.UploadpreprocesseddataCheckBox = uicheckbox(app.Panel_7);
            app.UploadpreprocesseddataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UploadpreprocesseddataCheckBoxValueChanged, true);
            app.UploadpreprocesseddataCheckBox.Text = 'Upload pre-processed data';
            app.UploadpreprocesseddataCheckBox.Value = true;
            app.AutomaticpreprocessingCheckBox = uicheckbox(app.Panel_7);
            app.AutomaticpreprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticpreprocessingCheckBoxValueChanged, true);
            app.AutomaticpreprocessingCheckBox.Text = 'Automatic pre-processing';
            app.HelpButton_2 = uibutton(app.Panel_7, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Text = 'Help';
            app.PreprocessingLabel = uilabel(app.UIFigure);
            app.PreprocessingLabel.Text = ' 3] Pre-processing';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Uni_wien_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PANOMICapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end