classdef PRS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HelpButton                      matlab.ui.control.Button
        ExampledataButton_2             matlab.ui.control.Button
        ExampledataButton               matlab.ui.control.Button
        DownloadResultscasegroupButton  matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        UITableCaseGroup                matlab.ui.control.Table
        UITableControlGroup             matlab.ui.control.Table
        LoadCaseGroupButton             matlab.ui.control.Button
        LoadControlGroupButton          matlab.ui.control.Button
        DownloadResultscontrolgroupButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PRSPolygenicRiskScoreLabel      matlab.ui.control.Label
        CalculatePRSButton              matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        PRS_hist_gauss                  matlab.ui.control.UIAxes
    end

properties (Access = private)
        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path

        MainApp;
        Geno;        % Genomic data
        Phe;

        control_data;
        case_data;
        control_scores;
        case_scores;

        results;
end

methods (Access = private)

        function updatePRS_hist_gauss(app)
            %histfit(app.PRS_hist_gauss, app.results.p_value);
            % 
            % % PRS histogram for control and case group on one plot
            % histogram(app.PRS_hist_gauss, app.control_scores, 'Normalization', 'pdf', 'FaceColor', 'b', 'FaceAlpha', 0.5);
            % hold on;
            % histogram(app.PRS_hist_gauss, app.case_scores, 'Normalization', 'pdf', 'FaceColor', 'r', 'FaceAlpha', 0.5);
            % 
            % % Fitting a normal distribution
            % pd_control = fitdist(app.control_scores, 'Normal');
            % x_values_control = linspace(min(app.control_scores), max(app.control_scores), 100);
            % y_values_control = pdf(pd_control, x_values_control);
            % plot(app.PRS_hist_gauss, x_values_control, y_values_control, 'b', 'LineWidth', 2);
            % 
            % pd_case = fitdist(app.case_scores, 'Normal');
            % x_values_case = linspace(min(app.case_scores), max(app.case_scores), 100);
            % y_values_case = pdf(pd_case, x_values_case);
            % plot(app.PRS_hist_gauss,x_values_case, y_values_case, 'r', 'LineWidth', 2);
            % 
            % title('PRS (Control(South Sweden) vs case(North Sweden))');
            % xlabel('PRS');
            % ylabel('Density');
            % legend({'Control – histogram', 'case – histogram', 'Control – normal curve', 'case – normal curve'});
            % 
            % hold off;

           % PRS histogram for control and case group on one plot
            histogram(app.PRS_hist_gauss, app.control_scores, 'Normalization', 'pdf', 'FaceColor', 'b', 'FaceAlpha', 0.5);
            hold(app.PRS_hist_gauss, 'on');  % Use hold on specific to app.PRS_hist_gauss
            histogram(app.PRS_hist_gauss, app.case_scores, 'Normalization', 'pdf', 'FaceColor', 'r', 'FaceAlpha', 0.5);
            
            % Fitting a normal distribution
            pd_control = fitdist(app.control_scores, 'Normal');
            x_values_control = linspace(min(app.control_scores), max(app.control_scores), 100);
            y_values_control = pdf(pd_control, x_values_control);
            plot(app.PRS_hist_gauss, x_values_control, y_values_control, 'b', 'LineWidth', 2);
            
            pd_case = fitdist(app.case_scores, 'Normal');
            x_values_case = linspace(min(app.case_scores), max(app.case_scores), 100);
            y_values_case = pdf(pd_case, x_values_case);
            plot(app.PRS_hist_gauss, x_values_case, y_values_case, 'r', 'LineWidth', 2);
            
            title(app.PRS_hist_gauss, 'PRS (Control (South Sweden) vs case (North Sweden))');
            xlabel(app.PRS_hist_gauss, 'PRS');
            ylabel(app.PRS_hist_gauss, 'Density');
            legend(app.PRS_hist_gauss, {'Control – histogram', 'case – histogram', 'Control – normal curve', 'case – normal curve'});
        
            hold(app.PRS_hist_gauss, 'off');  % Use hold off specific to app.PRS_hist_gauss

        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, geno_info, pheno_info)
            app.Geno = geno_info; % Genomic data
            app.Phe = pheno_info; % Phenotype data
            
            %Store main app in property
            app.MainApp = main_app;

        end

        % Callback function: CalculatePRSButton, PRS_hist_gauss
        function CalculatePRSButtonPushed(app, event)
            % app.Geno = rows2vars(app.Geno);
            % app.Geno(1, :) = [];
            % app.Geno.Properties.VariableNames(1) = app.Phe.Properties.VariableNames(1);
            % data = outerjoin(app.Geno, app.Phe, 'MergeKeys', true);
            % 
            % % Perform association testing for each SNP
            % app.results = table();
            % genotype_data_columns = app.Geno.Properties.VariableNames;
            % genotype_data_columns = genotype_data_columns(~strcmp(genotype_data_columns, app.Geno.Properties.VariableNames(1)));
            % 

            % First column – includes ID (identifiers)
            sample_id_col = "ID";
            
            %spravne transponovat
            app.Geno = rows2vars(app.Geno);
            app.Geno.Properties.VariableNames = cellstr(app.Geno{1,:});
            app.Geno(1,:) = []; 
            app.Geno.Properties.VariableNames(1) = "ID";
            app.Phe.Properties.VariableNames(1) = "ID";

            app.control_data.Properties.VariableNames(1) = "ID";
            app.case_data.Properties.VariableNames(1) = "ID";
                 % app.dataM = rows2vars(app.dataM);
                 % app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 % app.dataM(1,:) = []; 
                 % app.dataM_no = app.dataM(:, 2:end);
                 % app.UITableMet.Data = app.dataM;
                 % varHeadTable = app.UITableMet.Data;
                 % varHeadTable.Properties.VariableNames(1) = " ";

            % Merge genotype and phenotype data according ID (identifiers)
            data = outerjoin(app.Geno, app.Phe, 'MergeKeys', true);
            
            % data = rows2vars(data);
            % data.Properties.VariableNames = cellstr(data{1,:});
            % data(1,:) = []; 
            % data.Properties.VariableNames(1) = "ID";

            % Identificators –  control & case groups
            control_samples = ismember(data.(sample_id_col), app.control_data.(sample_id_col));
            case_samples = ismember(data.(sample_id_col), app.case_data.(sample_id_col));
            
            % Splitting the data into control and case group
            app.control_data = data(control_samples, :);
            app.case_data = data(case_samples, :);
            
            % Initialize table for results
            results_control = table();
            results_case = table();
            
            % Analysis for the control group
            for i = 1:size(app.Geno,2)
                snp = app.Geno.Properties.VariableNames{i};
                if ~strcmp(snp, sample_id_col)
                    x_control = app.control_data.(snp);
                    x_control = cell2mat(x_control);
                    x_control = [ones(size(x_control)), x_control];
                    y_control = app.control_data.phenotype;
                    [beta,~,~,~,stats] = regress(y_control, x_control);
                    beta = beta(2);
                    p_value = stats(3);
                    results_control = [results_control; table({snp}, p_value, beta)];
                end
            end
            
            % Analysis for the case group
            for i = 1:size(app.Geno,2)
                snp = app.Geno.Properties.VariableNames{i};
                if ~strcmp(snp, sample_id_col)
                    x_case = app.case_data.(snp);
                    x_case = cell2mat(x_case);
                    x_case = [ones(size(x_case)), x_case];
                    y_case = app.case_data.phenotype;
                    [beta,~,~,~,stats] = regress(y_case, x_case);
                    beta = beta(2);
                    p_value = stats(3);
                    results_case = [results_case; table({snp}, p_value, beta)];
                end
            end
            
            % Calculation of PRS for the control group
            control_genotypes = app.control_data(:, setdiff(app.Geno.Properties.VariableNames, sample_id_col));
            app.control_scores = zeros(size(app.control_data, 1), 1);
            for i = 1:height(results_control)
                snp = results_control.Var1{i};
                beta = results_control.beta(i);
                app.control_scores = app.control_scores + beta * cell2mat(control_genotypes.(snp));
            end
            
            % Calculation of PRS for the case group
            case_genotypes = app.case_data(:, setdiff(app.Geno.Properties.VariableNames, sample_id_col));
            app.case_scores = zeros(size(app.case_data, 1), 1);
            for i = 1:height(results_case)
                snp = results_case.Var1{i};
                beta = results_case.beta(i);
                app.case_scores = app.case_scores + beta * cell2mat(case_genotypes.(snp));
            end
        
             app.UITable.Data = results_control;
             varHeadTable = app.UITable.Data;
             varHeadTable.Properties.VariableNames(1) = " ";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITable.ColumnName = Names_varHeadTable;
             
             app.UITable_2.Data = results_case;
             varHeadTable_2 = app.UITable_2.Data;
             varHeadTable_2.Properties.VariableNames(1) = " ";
             Names_varHeadTable_2 = varHeadTable_2.Properties.VariableNames;
             app.UITable_2.ColumnName = Names_varHeadTable_2;

            updatePRS_hist_gauss(app);


        end

        % Button pushed function: DownloadResultscontrolgroupButton
        function DownloadResultscontrolgroupButtonPushed(app, event)
            outputFolder = 'outputs_PRS_control';

            if ~exist(outputFolder, 'dir')
               mkdir(outputFolder);
            end
            
            filename = fullfile(outputFolder, 'results.xlsx');
            writetable(app.control_scores, filename);
        end

        % Button pushed function: LoadControlGroupButton
        function LoadControlGroupButtonPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.control_data = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITableControlGroup.Data = app.control_data;
                 varHeadTable = app.UITableControlGroup.Data;
                 varHeadTable.Properties.VariableNames(1) = "ID";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableControlGroup.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end               


        end

        % Button pushed function: LoadCaseGroupButton
        function LoadCaseGroupButtonPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.case_data = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITableCaseGroup.Data = app.case_data;
                 varHeadTable = app.UITableCaseGroup.Data;
                 varHeadTable.Properties.VariableNames(1) = "ID";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITableCaseGroup.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end   
        end

        % Button pushed function: DownloadResultscasegroupButton
        function DownloadResultscasegroupButtonPushed(app, event)
            outputFolder = 'outputs_PRS_case';

            if ~exist(outputFolder, 'dir')
               mkdir(outputFolder);
            end
            
            filename = fullfile(outputFolder, 'results.xlsx');
            writetable(app.control_case, filename);
        end

        % Button pushed function: ExampledataButton
        function ExampledataButtonPushed(app, event)
    
          try 
             app.control_data = readtable('input_example\PRS_control_CentralEurope_vs_case_WesternEurope_6\ID_control_Ath_europe.csv');
             app.UITableControlGroup.Data = app.control_data;
             varHeadTable = app.UITableControlGroup.Data;
             varHeadTable.Properties.VariableNames(1) = "ID";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITableControlGroup.ColumnName = Names_varHeadTable;
             catch err
                 hErrIdx = warndlg(['Fails to open file: ',err.message]);
                 uiwait(hErrIdx);
             return;
          end
        
        end

        % Button pushed function: ExampledataButton_2
        function ExampledataButton_2Pushed(app, event)

           try 
             app.case_data = readtable('input_example\PRS_control_CentralEurope_vs_case_WesternEurope_6\ID_case_Ath_europe.csv');
             app.UITableCaseGroup.Data = app.case_data;
             varHeadTable = app.UITableCaseGroup.Data;
             varHeadTable.Properties.VariableNames(1) = "ID";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITableCaseGroup.ColumnName = Names_varHeadTable;
             catch err
                 hErrIdx = warndlg(['Fails to open file: ',err.message]);
                 uiwait(hErrIdx);
             return;
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PRS_hist_gauss = uiaxes(app.UIFigure);
            zlabel(app.PRS_hist_gauss, 'Z')
            app.PRS_hist_gauss.ButtonDownFcn = createCallbackFcn(app, @CalculatePRSButtonPushed, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BUT_logo_T.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.CalculatePRSButton = uibutton(app.UIFigure, 'push');
            app.CalculatePRSButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePRSButtonPushed, true);
            app.CalculatePRSButton.Text = 'Calculate PRS';
            app.PRSPolygenicRiskScoreLabel = uilabel(app.UIFigure);
            app.PRSPolygenicRiskScoreLabel.Text = '   PRS: Polygenic Risk Score';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.DownloadResultscontrolgroupButton = uibutton(app.UIFigure, 'push');
            app.DownloadResultscontrolgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadResultscontrolgroupButtonPushed, true);
            app.DownloadResultscontrolgroupButton.Text = {'Download Results'; 'control group'};
            app.LoadControlGroupButton = uibutton(app.UIFigure, 'push');
            app.LoadControlGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadControlGroupButtonPushed, true);
            app.LoadControlGroupButton.Text = 'Load Control Group';
            app.LoadCaseGroupButton = uibutton(app.UIFigure, 'push');
            app.LoadCaseGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCaseGroupButtonPushed, true);
            app.LoadCaseGroupButton.Text = 'Load Case Group';
            app.UITableControlGroup = uitable(app.UIFigure);
            app.UITableControlGroup.ColumnName = '';
            app.UITableControlGroup.ColumnRearrangeable = 'on';
            app.UITableControlGroup.RowName = {};
            app.UITableControlGroup.ColumnSortable = true;
            app.UITableControlGroup.ColumnEditable = true;
            app.UITableCaseGroup = uitable(app.UIFigure);
            app.UITableCaseGroup.ColumnName = '';
            app.UITableCaseGroup.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.DownloadResultscasegroupButton = uibutton(app.UIFigure, 'push');
            app.DownloadResultscasegroupButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadResultscasegroupButtonPushed, true);
            app.DownloadResultscasegroupButton.Text = {'Download Results'; 'case group'};
            app.ExampledataButton = uibutton(app.UIFigure, 'push');
            app.ExampledataButton.ButtonPushedFcn = createCallbackFcn(app, @ExampledataButtonPushed, true);
            app.ExampledataButton.Text = 'Example data';
            app.ExampledataButton_2 = uibutton(app.UIFigure, 'push');
            app.ExampledataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExampledataButton_2Pushed, true);
            app.ExampledataButton_2.Text = 'Example data';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end