classdef Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GradientLSTM           matlab.ui.control.NumericEditField
        GradientthresholdEditField_2Label  matlab.ui.control.Label
        EpochsLSTM             matlab.ui.control.NumericEditField
        MaximumnumberofepochsEditField_2Label  matlab.ui.control.Label
        GradientCNN            matlab.ui.control.NumericEditField
        GradientthresholdEditFieldLabel  matlab.ui.control.Label
        EpochsCNN              matlab.ui.control.NumericEditField
        MaximumnumberofepochsEditFieldLabel  matlab.ui.control.Label
        NumberOfDecisionTrees  matlab.ui.control.NumericEditField
        NumberofdecisiontreesEditField_2Label  matlab.ui.control.Label
        HelpButton             matlab.ui.control.Button
        SAVEButton             matlab.ui.control.Button
        LongshorttermmemoryLSTMnetworksLabel  matlab.ui.control.Label
        ConvolutationNeuralNetworksCNNLabel  matlab.ui.control.Label
        RandomForestLabel      matlab.ui.control.Label
        ParametercustomizationoptionsfornonlinearmodelsLabel  matlab.ui.control.Label
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
    end

properties (Access = private)
        MainApp;
        number_of_decisionsTrees_input;
        max_epoches_LSTM_input;
        max_epoches_CNN_input;
        gradientTreshold_LSTM_input;
        gradientTreshold_CNN_input;
end

methods (Access = private)


end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, number_of_decisionsTrees, max_epoches_LSTM, max_epoches_CNN, gradientTreshold_LSTM, gradientTreshold_CNN)
            app.number_of_decisionsTrees_input = number_of_decisionsTrees;
            app.max_epoches_LSTM_input = max_epoches_LSTM;
            app.max_epoches_CNN_input = max_epoches_CNN;
            app.gradientTreshold_LSTM_input = gradientTreshold_LSTM;
            app.gradientTreshold_CNN_input = gradientTreshold_CNN; 

            app.NumberOfDecisionTrees.Value = number_of_decisionsTrees;
            app.EpochsLSTM.Value =  max_epoches_LSTM;
            app.EpochsCNN.Value = max_epoches_CNN;
            app.GradientLSTM.Value = gradientTreshold_LSTM;
            app.GradientCNN.Value = gradientTreshold_CNN; 

            %Store main app in property
            app.MainApp = main_app;

        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
          
            app.number_of_decisionsTrees_input = app.NumberOfDecisionTrees.Value;
            app.max_epoches_LSTM_input = app.EpochsLSTM.Value;
            app.max_epoches_CNN_input = app.EpochsCNN.Value;
            app.gradientTreshold_LSTM_input = app.GradientLSTM.Value;
            app.gradientTreshold_CNN_input = app.GradientCNN.Value; 

            update_parameters(app.MainApp, app.number_of_decisionsTrees_input, app.max_epoches_LSTM_input, app.max_epoches_CNN_input, app.gradientTreshold_LSTM_input, app.gradientTreshold_CNN_input)

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BUT_logo_T.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.ParametercustomizationoptionsfornonlinearmodelsLabel = uilabel(app.UIFigure);
            app.ParametercustomizationoptionsfornonlinearmodelsLabel.Text = ' Parameter customization options for non-linear models';
            app.RandomForestLabel = uilabel(app.UIFigure);
            app.RandomForestLabel.Text = 'Random Forest ';
            app.ConvolutationNeuralNetworksCNNLabel = uilabel(app.UIFigure);
            app.ConvolutationNeuralNetworksCNNLabel.Text = 'Convolutation Neural Networks (CNN)';
            app.LongshorttermmemoryLSTMnetworksLabel = uilabel(app.UIFigure);
            app.LongshorttermmemoryLSTMnetworksLabel.Text = 'Long short-term memory (LSTM) networks';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.NumberofdecisiontreesEditField_2Label = uilabel(app.UIFigure);
            app.NumberofdecisiontreesEditField_2Label.Text = 'Number of decision trees:';
            app.NumberOfDecisionTrees = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfDecisionTrees.Value = 250;
            app.MaximumnumberofepochsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumnumberofepochsEditFieldLabel.Text = 'Maximum number of epochs:';
            app.EpochsCNN = uieditfield(app.UIFigure, 'numeric');
            app.EpochsCNN.Value = 20;
            app.GradientthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.GradientthresholdEditFieldLabel.Text = ' Gradient threshold:';
            app.GradientCNN = uieditfield(app.UIFigure, 'numeric');
            app.GradientCNN.Value = 2;
            app.MaximumnumberofepochsEditField_2Label = uilabel(app.UIFigure);
            app.MaximumnumberofepochsEditField_2Label.Text = 'Maximum number of epochs:';
            app.EpochsLSTM = uieditfield(app.UIFigure, 'numeric');
            app.EpochsLSTM.Value = 20;
            app.GradientthresholdEditField_2Label = uilabel(app.UIFigure);
            app.GradientthresholdEditField_2Label.Text = ' Gradient threshold:';
            app.GradientLSTM = uieditfield(app.UIFigure, 'numeric');
            app.GradientLSTM.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end